classdef ComSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        Label                    matlab.ui.control.Label
        DropdownComCode          matlab.ui.control.DropDown
        Label_2                  matlab.ui.control.Label
        DropdownComBaud          matlab.ui.control.DropDown
        Label_3                  matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        EditField_2              matlab.ui.control.EditField
        Label_5                  matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        KBLabel                  matlab.ui.control.Label
        EditBufferSize           matlab.ui.control.NumericEditField
        ButtonOpenCloseSerial    matlab.ui.control.Button
        Label_6                  matlab.ui.control.Label
        LampStatus               matlab.ui.control.Lamp
        ButtonOK                 matlab.ui.control.Button
        ButtonCancel             matlab.ui.control.Button
        Panel_2                  matlab.ui.container.Panel
        BytesEditFieldLabel      matlab.ui.control.Label
        EditBytes                matlab.ui.control.NumericEditField
        CheckBoxHEX              matlab.ui.control.CheckBox
        TextAreaReceivedData     matlab.ui.control.TextArea
        Panel_3                  matlab.ui.container.Panel
        Label_7                  matlab.ui.control.Label
        DropdownComCode_2        matlab.ui.control.DropDown
        Label_8                  matlab.ui.control.Label
        DropdownComBaud_2        matlab.ui.control.DropDown
        EditField_4Label         matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
        EditField_5Label         matlab.ui.control.Label
        EditField_5              matlab.ui.control.EditField
        EditField_6Label         matlab.ui.control.Label
        EditField_6              matlab.ui.control.NumericEditField
        KBLabel_2                matlab.ui.control.Label
        EditBufferSize_2         matlab.ui.control.NumericEditField
        ButtonSend               matlab.ui.control.Button
        EditSend                 matlab.ui.control.EditField
        LampStatus_2             matlab.ui.control.Lamp
        ButtonOpenCloseSerial_2  matlab.ui.control.Button
        ButtonResetSerial        matlab.ui.control.Button
    end

    properties (Access = public)
        CallingApp;
        
        SCom;
        ComCode; % 串口号
        ComBaud; % 波特率
        ComData = [];
        ComStatus;
        InputBufferSize =  128*1024;
        ReceivedData = '';

        SCom2;
        ComCode2; % 串口号
        ComBaud2; % 波特率
        ComStatus2;
        ComData2 = [];
        InputBufferSize2 = 4*1024;
        
        HexRow = [];

end
    
    
    methods (Access = private)
        
        function getDataComParameter(app)
            app.ComCode = app.DropdownComCode.Value;
            app.ComBaud = str2double(app.DropdownComBaud.Value);
            app.InputBufferSize = app.EditBufferSize.Value*1024;
            
        end
        function openDataCom(app)
            getDataComParameter(app);
            app.ComData = [];
            app.HexRow = [];
            app.SCom = serial(app.ComCode);
            set(app.SCom, 'BaudRate', app.ComBaud ,...
                'Parity', 'NONE', 'DataBits', 8, 'StopBits', 1);
            app.SCom.BytesAvailableFcnMode = 'byte'; 
            app.SCom.BytesAvailableFcnCount = 128;
            app.SCom.InputBufferSize = app.InputBufferSize;
%             app.SCom.ReadAsyncMode='continuous';
            app.SCom.TimeOut = 1;
%             app.SCom.Terminator='CR';
            app.SCom.BytesAvailableFcn = @app.receiveDataCallback;

            %% 尝试打开串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    fclose(app.SCom);
                end
                fopen(app.SCom);  %打开串口
                
            catch   % 若串口打开失败，提示“串口打开失败！”
                msgbox('串口打开失败！');
                return;
            end
       end
        
        function closeDataCom(app)
             %% 尝试关闭串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    app.TextAreaReceivedData.Value = '';
                    app.EditBytes.Value = 0;
                    fclose(app.SCom);
                end
            catch   % 若串口关闭失败，提示“串口关闭失败！”
                msgbox('串口关闭失败！');
                return;
            end
        end


        function receiveDataCallback(app,event,src)
            len = app.SCom.BytesAvailable;
            if(len>0)
                [data,~]  = fread(app.SCom,len);
                HexColumn = dec2hex(data);
                app.ComData = [app.ComData;dec2hex(data)];
                for i=1:len
                    app.HexRow = strcat(app.HexRow,HexColumn(i,:));   % 一个行向量
                end
                app.TextAreaReceivedData.Value = app.HexRow;
            end
            app.EditBytes.Value = app.SCom.ValuesReceived;
        end
    
        function receiveDataCallback2(app,event,src)
            len = app.SCom2.BytesAvailable;
            if(len>0)
                [data,~]  = fread(app.SCom2,len);
                HexColumn = dec2hex(data);
                app.ComData2 = [app.ComData2;dec2hex(data)];
                for i=1:len
                    app.HexRow = strcat(app.HexRow,HexColumn(i,:));   % 一个行向量
                end
                app.TextAreaReceivedData.Value = app.HexRow;
            end
            app.EditBytes.Value = app.SCom2.ValuesReceived;
        end   
    
        function setStatus(app)
            app.ComStatus = app.SCom.Status;
            switch app.ComStatus
                case 'closed'
                    app.LampStatus.Color = [1,0,0];
                    app.ButtonOpenCloseSerial.Text = '打开串口';
                case 'open'
                    app.LampStatus.Color = [0,1,0];
                    app.ButtonOpenCloseSerial.Text = '关闭串口';
                otherwise
            end
        end
    
        function setStatus2(app)
            Status = app.SCom2.Status;
            switch Status
                case 'closed'
                    app.LampStatus_2.Color = [1,0,0];
                    app.ButtonOpenCloseSerial_2.Text = '打开串口';
                case 'open'
                    app.LampStatus_2.Color = [0,1,0];
                    app.ButtonOpenCloseSerial_2.Text = '关闭串口';
                otherwise
            end
        end
    
        function getControlComParameter(app)
            app.ComCode2 = app.DropdownComCode_2.Value;
            app.ComBaud2 = str2double(app.DropdownComBaud_2.Value);
            app.InputBufferSize2 = app.EditBufferSize_2.Value*1024;
            
        end
        function openControlCom(app)
            getControlComParameter(app);
            app.ComData2 = [];
            app.HexRow = [];
            app.SCom2 = serial(app.ComCode2);
            set(app.SCom2, 'BaudRate', app.ComBaud2 ,...
                'Parity', 'NONE', 'DataBits', 8, 'StopBits', 1);
            app.SCom2.BytesAvailableFcnMode = 'byte'; 
            app.SCom2.BytesAvailableFcnCount = 20;
            app.SCom2.InputBufferSize = app.InputBufferSize;
%             app.SCom.ReadAsyncMode='continuous';
            app.SCom2.TimeOut = 1;
%             app.SCom.Terminator='CR';
            app.SCom2.BytesAvailableFcn = @app.receiveDataCallback2;

            %% 尝试打开串口
            try
                if(strcmp(app.SCom2.Status,'open'))
                    fclose(app.SCom2);
                end
                fopen(app.SCom2);  %打开串口
                
            catch   % 若串口打开失败，提示“串口打开失败！”
                msgbox('串口打开失败！');
                return;
            end
        end
        
        function closeControlCom(app)
             %% 尝试关闭串口
            try
                if(strcmp(app.SCom2.Status,'open'))
                    app.TextAreaReceivedData.Value = '';
                    fclose(app.SCom2);
                end
            catch   % 若串口关闭失败，提示“串口关闭失败！”
                msgbox('串口关闭失败！');
                return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            movegui(app.UIFigure,'center');
            app.CallingApp = mainApp;

        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            closeDataCom(app);
            closeControlCom(app);
            delete(app);
        end

        % Button pushed function: ButtonOK
        function ButtonOKPushed(app, event)
            getDataComParameter(app);
            setDataCom(app.CallingApp,app.ComCode,app.ComBaud,app.InputBufferSize);
            getControlComParameter(app);
            setControlCom(app.CallingApp,app.ComCode2,app.ComBaud2,app.InputBufferSize2);

%             closeDataCom(app);
%             closeControlCom(app);
            delete(app);
        end

        % Callback function
        function ButtonTestSerialPushed(app, event)
            getDataComParameter(app);
            openDataCom(app);
            setStatus(app);
        end

        % Button pushed function: ButtonResetSerial
        function ButtonResetSerialPushed(app, event)
            delete(instrfindall);
%             openDataCom(app); 
%             openControlCom(app); 
%             setStatus(app);

        end

        % Button pushed function: ButtonOpenCloseSerial
        function ButtonOpenCloseSerialPushed(app, event)
            TextButton = app.ButtonOpenCloseSerial.Text;
            switch TextButton
                case '打开串口'
                    app.EditBytes.Value = 0;
                    app.ComData = [];
                    openDataCom(app);
                case '关闭串口'
                    closeDataCom(app);
                otherwise
            end
%             app.EditReceivedData.set('Max',3);
            setStatus(app);
        end

        % Button pushed function: ButtonSend
        function ButtonSendPushed(app, event)
            SendData = app.EditSend.Value;
            fprintf(app.SCom2,'%c',SendData);
            app.TextAreaReceivedData.Value = SendData;

        end

        % Button pushed function: ButtonOpenCloseSerial_2
        function ButtonOpenCloseSerial_2Pushed(app, event)
            TextButton = app.ButtonOpenCloseSerial_2.Text;
            switch TextButton
                case '打开串口'
                    app.EditBytes.Value = 0;
                    openControlCom(app);
                case '关闭串口'
                    closeControlCom(app);
                otherwise
            end
            setStatus2(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '串口参数设置';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '探测串口参数';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '串口号';
            app.DropdownComCode = uidropdown(app.Panel);
            app.DropdownComCode.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.DropdownComCode.Editable = 'on';
            app.DropdownComCode.Value = 'COM3';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '波特率';
            app.DropdownComBaud = uidropdown(app.Panel);
            app.DropdownComBaud.Items = {'115200'};
            app.DropdownComBaud.Editable = 'on';
            app.DropdownComBaud.Value = '115200';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '数据位';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 1024];
            app.EditField.Editable = 'off';
            app.EditField.Value = 8;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '校验位';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '无';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '停止位';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Limits = [0 1024];
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 1;
            app.KBLabel = uilabel(app.Panel);
            app.KBLabel.Text = '缓冲区(KB)';
            app.EditBufferSize = uieditfield(app.Panel, 'numeric');
            app.EditBufferSize.Limits = [0 4096];
            app.EditBufferSize.Tooltip = {'6个通道的浮点数据，每个点24字节。'};
            app.EditBufferSize.Value = 128;
            app.ButtonOpenCloseSerial = uibutton(app.Panel, 'push');
            app.ButtonOpenCloseSerial.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenCloseSerialPushed, true);
            app.ButtonOpenCloseSerial.Text = '打开串口';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '状态';
            app.LampStatus = uilamp(app.Panel);
            app.ButtonOK = uibutton(app.UIFigure, 'push');
            app.ButtonOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonOKPushed, true);
            app.ButtonOK.Text = '确定';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Text = '取消';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '串口数据';
            app.BytesEditFieldLabel = uilabel(app.Panel_2);
            app.BytesEditFieldLabel.Text = '字节数(Bytes)';
            app.EditBytes = uieditfield(app.Panel_2, 'numeric');
            app.CheckBoxHEX = uicheckbox(app.Panel_2);
            app.CheckBoxHEX.Text = 'HEX 显示';
            app.CheckBoxHEX.Value = true;
            app.TextAreaReceivedData = uitextarea(app.Panel_2);
            app.TextAreaReceivedData.Value = {'Waiting>>>'};
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '控制串口参数';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '串口号';
            app.DropdownComCode_2 = uidropdown(app.Panel_3);
            app.DropdownComCode_2.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.DropdownComCode_2.Editable = 'on';
            app.DropdownComCode_2.Value = 'COM4';
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '波特率';
            app.DropdownComBaud_2 = uidropdown(app.Panel_3);
            app.DropdownComBaud_2.Items = {'115200'};
            app.DropdownComBaud_2.Editable = 'on';
            app.DropdownComBaud_2.Value = '115200';
            app.EditField_4Label = uilabel(app.Panel_3);
            app.EditField_4Label.Text = '数据位';
            app.EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_4.Limits = [0 1024];
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 8;
            app.EditField_5Label = uilabel(app.Panel_3);
            app.EditField_5Label.Text = '校验位';
            app.EditField_5 = uieditfield(app.Panel_3, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = '无';
            app.EditField_6Label = uilabel(app.Panel_3);
            app.EditField_6Label.Text = '停止位';
            app.EditField_6 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_6.Limits = [0 1024];
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 1;
            app.KBLabel_2 = uilabel(app.Panel_3);
            app.KBLabel_2.Text = '缓冲区(KB)';
            app.EditBufferSize_2 = uieditfield(app.Panel_3, 'numeric');
            app.EditBufferSize_2.Limits = [0 128];
            app.EditBufferSize_2.Tooltip = {'每秒50次回传，每次6字节。'};
            app.EditBufferSize_2.Value = 4;
            app.ButtonSend = uibutton(app.Panel_3, 'push');
            app.ButtonSend.ButtonPushedFcn = createCallbackFcn(app, @ButtonSendPushed, true);
            app.ButtonSend.Text = '发送测试';
            app.EditSend = uieditfield(app.Panel_3, 'text');
            app.EditSend.Value = '无';
            app.LampStatus_2 = uilamp(app.Panel_3);
            app.ButtonOpenCloseSerial_2 = uibutton(app.Panel_3, 'push');
            app.ButtonOpenCloseSerial_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenCloseSerial_2Pushed, true);
            app.ButtonOpenCloseSerial_2.Text = '打开串口';
            app.ButtonResetSerial = uibutton(app.UIFigure, 'push');
            app.ButtonResetSerial.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetSerialPushed, true);
            app.ButtonResetSerial.Text = '重置串口';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end