classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MenuFile                    matlab.ui.container.Menu
        MenuVersionSelect           matlab.ui.container.Menu
        MenuVersionDeveloper        matlab.ui.container.Menu
        MenuVersionApplier          matlab.ui.container.Menu
        MenuExit                    matlab.ui.container.Menu
        MenuSettings                matlab.ui.container.Menu
        MenuComSettings             matlab.ui.container.Menu
        MenuInitialRobost           matlab.ui.container.Menu
        MenuLPFSettings             matlab.ui.container.Menu
        MenuImageLib                matlab.ui.container.Menu
        MenuSignalLib               matlab.ui.container.Menu
        MenuTrainBP                 matlab.ui.container.Menu
        MenuImageTrain              matlab.ui.container.Menu
        MenuGroundTrain             matlab.ui.container.Menu
        MenuCharisticValue          matlab.ui.container.Menu
        MenuTrainSettings           matlab.ui.container.Menu
        MenuTrain                   matlab.ui.container.Menu
        MenuSaveCoef                matlab.ui.container.Menu
        MenuHelp                    matlab.ui.container.Menu
        MenuManual                  matlab.ui.container.Menu
        MenuVersionInfo             matlab.ui.container.Menu
        UIAxes                      matlab.ui.control.UIAxes
        TabGroupDomainRoute         matlab.ui.container.TabGroup
        TabDomainRoute              matlab.ui.container.Tab
        Label_64                    matlab.ui.control.Label
        EditFieldX                  matlab.ui.control.NumericEditField
        EditFieldY                  matlab.ui.control.NumericEditField
        PanelDomain                 matlab.ui.container.Panel
        ButtonDomain                matlab.ui.control.Button
        ButtonAddVertex             matlab.ui.control.Button
        ButtonDomainClear           matlab.ui.control.Button
        ButtonAddVertex_2           matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ButtonClearRoute            matlab.ui.control.Button
        ButtonWayPointRouting       matlab.ui.control.Button
        ButtonManualWayPoint        matlab.ui.control.Button
        ButtonAutoWayPoint          matlab.ui.control.Button
        SwitchMediumType            matlab.ui.control.Switch
        Label_63                    matlab.ui.control.Label
        ScanWidth                   matlab.ui.control.NumericEditField
        SwitchCoordinateType        matlab.ui.control.Switch
        TabScan                     matlab.ui.container.Tab
        SwitchOrientation           matlab.ui.control.Switch
        Label_58                    matlab.ui.control.Label
        EditScanRadius              matlab.ui.control.NumericEditField
        ButtonGroup_2               matlab.ui.container.ButtonGroup
        CH1Button                   matlab.ui.control.RadioButton
        CH2Button                   matlab.ui.control.RadioButton
        CH3Button                   matlab.ui.control.RadioButton
        Switch2D3D                  matlab.ui.control.Switch
        EditFieldSensorPosY         matlab.ui.control.NumericEditField
        Label_55                    matlab.ui.control.Label
        EditFieldSensorPosX         matlab.ui.control.NumericEditField
        ButtonStartScan             matlab.ui.control.Button
        ButtonPrepareScan           matlab.ui.control.Button
        ButtonStopScan              matlab.ui.control.Button
        ButtonContour               matlab.ui.control.Button
        SwitchScanMethod            matlab.ui.control.Switch
        Label_49                    matlab.ui.control.Label
        EditCurrentAngle            matlab.ui.control.NumericEditField
        Label_75                    matlab.ui.control.Label
        CurScanStatus               matlab.ui.control.TextArea
        Label_76                    matlab.ui.control.Label
        EditScanHeight              matlab.ui.control.NumericEditField
        Label_77                    matlab.ui.control.Label
        EditFieldPreStatus          matlab.ui.control.NumericEditField
        Label_78                    matlab.ui.control.Label
        EditFieldCurStatus          matlab.ui.control.NumericEditField
        TabPositionRecognize        matlab.ui.container.Tab
        PanelPosition               matlab.ui.container.Panel
        ButtonPosition              matlab.ui.control.Button
        ButtonMarkPosition          matlab.ui.control.Button
        ButtonRecognize             matlab.ui.control.Button
        PanelRecognize              matlab.ui.container.Panel
        ButtonFileTargetRecognize   matlab.ui.control.Button
        ButtonMultiTargetRecognize  matlab.ui.control.Button
        EditFieldTargetType         matlab.ui.control.EditField
        TabImageRecognition         matlab.ui.container.Tab
        ButtonImageLoad             matlab.ui.control.Button
        EditFieldImageLoad          matlab.ui.control.EditField
        ButtonImageRecognize        matlab.ui.control.Button
        Label_30                    matlab.ui.control.Label
        EditFieldImageType          matlab.ui.control.EditField
        ButtonImageExport           matlab.ui.control.Button
        EditFieldIntroduce          matlab.ui.control.EditField
        UIAxes2                     matlab.ui.control.UIAxes
        TabDebug                    matlab.ui.container.Tab
        cmLabel_2                   matlab.ui.control.Label
        EditAccumulateDeltaY        matlab.ui.control.NumericEditField
        cmLabel_3                   matlab.ui.control.Label
        EditMinSpan                 matlab.ui.control.NumericEditField
        DataSourceButtonGroup       matlab.ui.container.ButtonGroup
        Button                      matlab.ui.control.RadioButton
        Button2                     matlab.ui.control.RadioButton
        Knob                        matlab.ui.control.DiscreteKnob
        SwitchEquipment             matlab.ui.control.Switch
        Label_27                    matlab.ui.control.Label
        Label_32                    matlab.ui.control.Label
        Label_46                    matlab.ui.control.Label
        Label_48                    matlab.ui.control.Label
        EditScanAngle               matlab.ui.control.NumericEditField
        Label_31                    matlab.ui.control.Label
        EditFieldCurrentTime        matlab.ui.control.EditField
        Label_53                    matlab.ui.control.Label
        Label_54                    matlab.ui.control.Label
        EditFs                      matlab.ui.control.NumericEditField
        Label_56                    matlab.ui.control.Label
        DropDownDirection           matlab.ui.control.DropDown
        Label_60                    matlab.ui.control.Label
        EditScanVelocity_2          matlab.ui.control.NumericEditField
        cmLabel_4                   matlab.ui.control.Label
        RobotWidthEditField         matlab.ui.control.NumericEditField
        Label_39                    matlab.ui.control.Label
        EditScanVelocity            matlab.ui.control.NumericEditField
        EditDeltaY                  matlab.ui.control.NumericEditField
        Panel_2                     matlab.ui.container.Panel
        LampStatus                  matlab.ui.control.Lamp
        LampStatus_2                matlab.ui.control.Lamp
        ButtonCom1Control           matlab.ui.control.Button
        ButtonCom2Control           matlab.ui.control.Button
        ButtonInitialRobost         matlab.ui.control.Button
        ButtonCurrentPos            matlab.ui.control.Button
        EditRobotPosY               matlab.ui.control.NumericEditField
        EditRobotPosX               matlab.ui.control.NumericEditField
        Label_52                    matlab.ui.control.Label
        EditFieldOriention          matlab.ui.control.NumericEditField
        Label_50                    matlab.ui.control.Label
        EditFieldRobotLon           matlab.ui.control.NumericEditField
        Label_51                    matlab.ui.control.Label
        EditFieldRobotLat           matlab.ui.control.NumericEditField
        ButtonEmergency             matlab.ui.control.Button
        ButtonResetCOM              matlab.ui.control.Button
        Panel_3                     matlab.ui.container.Panel
        ButtonTableExport           matlab.ui.control.Button
        ButtonTableDataDelete       matlab.ui.control.Button
        ButtonRecoginizeSelected    matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        ButtonCoordinateBack        matlab.ui.control.Button
    end

    
    properties (Access = private)
        % ----------- 全局部分 ----------- %
        VerSwitch = 0;
        Timer;
        nAddVertex = 1;   % 选择在坐标区内点击鼠标的功能，1为增加顶点，2为前进点，3为目标选择。
        RobotLat;
        RobotLon;
        RobotYaw = 270;
        RobotYawPro = 270;
        RobotStatus;
        bCheckEnable = true; % Description
        RobotLat0 = 41.760870665;
        RobotLon0 = 123.441537512;
        InitRobotLat = 41.760870665;
        InitRobotLon = 123.441537512;
        RobotLat0Contour = 41.760870665;     % 点击区域探扫开始时的位置，作为成像的原点。不用了，直接更新InitRobotLon。
        RobotLon0Contour = 123.441537512;
        offsetX0 = 0;     % 每次区域探扫开始时，更新此值。
        offsetY0 = 0;
        M_LATLON = 360/(2*pi*6371*1e3);
        LATLON_M = (2*pi*6371*1e3)/360;
        DeltaLatA = 0.335*360/(2*pi*6371*1e3);    % 横臂对应的纬度差。
        DeltaLatB = 0.860*360/(2*pi*6371*1e3);    % 竖臂对应的纬度差。
        % ----------- 串口部分 ----------- %
        ComCode = 'COM3'               % 串口号
        ComBaud = 115200;              % 波特率
        InputBufferSize = 128*1024;    % 缓冲区大小。六通道，格式'float32'  24*16
        SCom;
        ComData;
        DataLen = 0;
        DispData = [];
        
        ComCode2 = 'COM4';              % 串口号
        ComBaud2 = 115200;             % 波特率
        InputBufferSize2 = 4*1024;    % 缓冲区大小。
        SCom2;
        ComData2;
        
        Equipment = '型号1';        
        ControlOrder = ['7D';'08';'55';'08';'08';'08';'00';'7E'];   % 控制指令格式。
        % ----------- 神经网络部分 ----------- %
        Coef;                  % 权值
        
        % ----------- 扫描参数部分 ----------- %
        ScanStatus = 0;             % 初始扫描状态
        Fs = 80;                    % 采样速率
        ScanRadius = 1.5 ;                % 扫描半径
        ScanVelocity = 20;              % 扫描速度
        CurrentAngle = 135;                 % 当前角度
        ScanDirection;                    % 扫描方向
        DeltaY = 10;                    % Y 轴增量
        AccumulateDeltaY = 0;           % 累计增量
        ScanAngle = 50;
        Direction = '00';
        Accuracy = 180/32768;         % 角度计精度。
        % ----------- 区域成像部分 ----------- %
        SN = 1;            % 在某一区域的第几次扫描数据
        AreaData = [];     % 在某一区域的扫描数据缓冲区
        AreaDataFile = []; % 在某一区域的扫描数据的保存的文件
        COM1DataReadable = false;     % 设置是否响应串口，<扫描开始>响应，<停止扫描>不响应
        COM2DataReadable = true;      % 设置是否响应串口2
        Array1;
        Array2;
        Array3;
        vTHETA;
        vDeltaY;
        GridX = [];           % 经度矩阵（以与原点的相对位置表示）
        GridY = [];           % 纬度矩阵（以与原点的相对位置表示）
        Angle4Debug = [];
        ContourFile;
        % ----------- 目标定位及识别部分 ----------- %
        MeanValue = [345 325 425];
        XPos;
        YPos;
        TargetInArrayRow;
        TargetInArrayColumn;
        CH1Threshold = 50;            % 通道1的门限值，判定有目标。8/12/20
        TargetMinSpan = 10;           % 可以识别的目标最小间隔(cm)。
        X;
        Y;
        Z;
        TextHandle = [];
        preContour = [];
        hTriangle = [];
        hRectangle = [];
        hLineBehind = [];
        hLineForward = [];
        realTime = 1;
        % ----------- 雷区绘制与路径规划部分 ----------- %
        xRouteBehind = [];     % 已经行进的路径
        yRouteBehind = [];
        xRouteForward = [];    % 即将行进的路径
        yRouteForward = [];
        xPolygon = [];
        yPolygon = [];
        xDomain = [];
        yDomain = [];
        hPoint= [];
        hAxesText = [];
        bCaptureCurrentPoint = false;
        bRectangle = false;
        bAutoWayPoint = false;
        BehindLineColor = 1;    % 1蓝色 2红色 3不划线
        % ----------- 地面目标识别部分 ----------- %
        ImageLib = strcat(pwd,'\Settings\categortyClassificationMat.mat');
        ImagePath = pwd;
        Introduce = {'58式地雷的说明',...
            '72式地雷的说明',...
            '79式地雷的说明'};
    end
    
    
    properties (Access = public)
        % ------------ 学习部分 ----------- %
        FileSavePath = '';           % 特征值文件位置
        FileSettings = strcat(pwd,'\Settings\TrainSettings.mat');           % 神经网络参数文件位置
        FileCoef = strcat(pwd,'\Settings\Coef-合肥-1109.mat');               % 权值文件
        
    end
    
    
    methods (Access = public)
        
        function setDataCom(app,ComCode,Baud,BufferSize)
            app.ComCode = ComCode;
            app.ComBaud = Baud;
            app.InputBufferSize = BufferSize;
        end
        function setControlCom(app,ComCode,Baud,BufferSize)
            app.ComCode2 = ComCode;
            app.ComBaud2 = Baud;
            app.InputBufferSize2 = BufferSize;
        end
        function setScan(app,Fs,Radius,Velocity,Angle)
            app.Fs = Fs;
            app.ScanRadius = Radius;
            app.ScanVelocity = Velocity;
            app.ScanAngle = Angle;
        end
        
        function setSettingFile(app,FileName)
            app.FileSettings = FileName;
        end
        
        function setTableView(app,TableColumnName,TableData)
            % Add data to the Table UI Component
            app.UITable.ColumnName = TableColumnName;
            app.UITable.Data = TableData;
        end
        
        function setCoefFile(app,Coef)
            app.Coef = Coef;
        end
        
        function setRealTimePlot(app,Data,XRange)
%             p = plot(app.UIAxes,Data,'EraseMode','background','MarkerSize',5);
            plot(app.UIAxes,Data);
%             axis(app.UIAxes,[0 1500 -inf inf])
%             legend(app.UIAxes,{'1通道','2通道','3通道'});  %,'Location','SouthEast');
%             legend('CH1','CH2','CH3');
        end
    end
    
    methods (Access = private)

        function createDataCom(app)
            app.SCom = serial(app.ComCode);
            %             app.ComData = [];
            app.DispData = zeros(3,1);
            app.DataLen = 0;
            set(app.SCom, 'BaudRate', app.ComBaud ,...
                'Parity', 'NONE', 'DataBits', 8, 'StopBits', 1);
            
            app.SCom.BytesAvailableFcnMode = 'byte';
            app.SCom.BytesAvailableFcnCount = 32*10;
            app.SCom.InputBufferSize = app.InputBufferSize;
            %             app.SCom.ReadsyncMode='continuous';
            app.SCom.TimeOut = 1;
            %             app.SCom.Terminator='CR';
            app.SCom.BytesAvailableFcn = @app.receiveDataCallback;
        end

        function openDataCom(app)
            createDataCom(app);
            %% 尝试打开串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    fclose(app.SCom);
                end
                fopen(app.SCom);  %打开串口
            catch   % 若串口打开失败，提示“串口打开失败！”
                msgbox('数据串口打开失败！');
                return;
            end
            app.Equipment = app.SwitchEquipment.Value;
        end
        
        function closeDataCom(app)
            %% 尝试关闭串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    fclose(app.SCom);
                    %                     delete(app.SCom)
                end
            catch   % 若串口关闭失败，提示“串口关闭失败！”
                msgbox('数据串口关闭失败！');
                return;
            end
        end
        
        function createControlCom(app)
%             app.ComData2 = [];
            app.SCom2 = serial(app.ComCode2);
            set(app.SCom2, 'BaudRate', app.ComBaud2 ,...
                'Parity', 'NONE', 'DataBits', 8, 'StopBits', 1);
            app.SCom2.BytesAvailableFcnMode = 'byte';
            app.SCom2.BytesAvailableFcnCount = 100;
            app.SCom2.InputBufferSize = app.InputBufferSize2;
            %             app.SCom.ReadsyncMode='continuous';
            app.SCom2.TimeOut = 1;
            %             app.SCom.Terminator='CR';
            app.SCom2.BytesAvailableFcn = @app.receiveDataCallback2;
        end
        
        function openControlCom(app)

            createControlCom(app);

            %% 尝试打开串口
            try
                if(strcmp(app.SCom2.Status,'open'))
                    fclose(app.SCom2);
                end
                fopen(app.SCom2);  %打开串口
            catch   % 若串口打开失败，提示“串口打开失败！”
                msgbox('控制串口打开失败！');
                return;
            end
            
        end
        
        function closeControlCom(app)
            %% 尝试关闭串口
            try
                if(strcmp(app.SCom2.Status,'open'))
                    fclose(app.SCom2);
                    %                     delete(app.SCom2);
                end
            catch   % 若串口关闭失败，提示“串口关闭失败！”
                msgbox('控制串口关闭失败！');
                return;
            end
        end
        
        function Order = setControlOrder(app,Index,Value)
%             len = length(Index);
            tmp = app.ControlOrder;
%             for i = 1:len
%                 tmp(Index(i),:) = Value(2*i-1:2*i);
%             end
            tmp(Index) = Value;
            Order = hex2dec(tmp);
        end
        
        
        function receiveDataCallback(app,event,src)
            switch app.MenuVersionDeveloper.Checked
                case 'on'
                    developerComCallbace1(app);
                case 'off'  % 实操应用版本，读取机器人位置和扫描就绪、扫描停止指令
                    applierComCallbace1(app);
                otherwise
            end
        end
        
        function receiveDataCallback2(app,event,src)
            switch app.MenuVersionDeveloper.Checked
                case 'on'
                    developerComCallbace2(app);
                case 'off'  % 实操应用版本，读取机器人位置和扫描就绪、扫描停止指令
                    applierComCallbace2(app);
                otherwise
            end
        end
        
        function Angle = angleToFloat(app,data)
            len = length(data);
            Angle = zeros(1,len/2);
            for i = 1:len/2
                if(data(2*i)>127)
                    x = data(2*i)-128-1;
                    x = bitxor(x,127);
                    Angle(i) = -(x*256 - data(2*i-1));
                else
                    Angle(i) = data(2*i)*256 + data(2*i-1);
                end
            end
        end
        
        %         function scanStatus(app,str)
        %             t = datetime('now','Format','yyyy-MM-dd''-''HHmmss');
        %             str = strcat(datestr(t,'mmdd-HHMMSS: '),str);
        %             app.TextAreaScanStatus.Value = strcat(app.TextAreaScanStatus.Value,newline,str);
        %         end
        
        function getScanParameter(app)
            app.Fs = app.EditFs.Value;
            app.ScanRadius = app.EditScanRadius.Value;
            app.ScanVelocity = app.EditScanVelocity.Value;
            app.ScanAngle = app.EditScanAngle.Value;
            app.DeltaY = app.EditDeltaY.Value;
            app.AccumulateDeltaY = app.EditAccumulateDeltaY.Value;
        end
        
        function DataFileName = saveData(app)
            %  保存到文件
            t = datetime('now','Format','yyyy-MM-dd''-''HHmmss');
            DataFileName = [pwd,'\proData\Amplitude',datestr(t,'yyyymmdd-HHMMSS'),'.dat'];
%             AngleFileName = [pwd,'\proData\方向和角度',datestr(t,'yyyymmdd-HHMMSS'),'.dat'];
            
%             if(strcmp(app.ScanDirection,'顺时针'))
%                 app.Direction = '00';
%             else
%                 app.Direction = '01';
%             end
            
            fid = fopen(DataFileName,'w');
            if(fid == -1)
                error('保存幅度文件路径错误');
            end
            fwrite(fid,app.ComData,'float32');
            fclose(fid);
            
%             fid = fopen(AngleFileName,'w');
%             if(fid == -1)
%                 error('保存角度文件路径错误');
%             end
%             fwrite(fid,[sprintf('%02X',app.Direction);...
%                 sprintf('%02X',app.AccumulateDeltaY);app.ComData2],'uint8');
%             fwrite(fid,[app.Direction;app.ComData2],'uint8');
%             fclose(fid);
%             str = '文件保存成功！';
%             scanStatus(app,str);            
% % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
            %  保存到缓冲区
            a.SQNumber = app.SN;
            app.SN = app.SN + 1;
%             a.Direction = str2double(app.Direction);
%             tmp = angleToFloat(app,app.ComData2)*180/32768;
%             a.Angle = tmp(21:length(tmp)-25);   % 去掉数据的抖动  2019.06.12
            a.Value = app.ComData;
%             a.AccumulateDeltaY = app.AccumulateDeltaY;
            
            app.AreaData{app.SN} = a;
            
        end
        
        
        function [X,Y,Z] = developerContour(app,realTime,offsetX,offsetY)
%             cla(app.UIAxes);
            [m,n] = size(app.Array1);
            Z = zeros(m,n,3);
            [X,Y,Z(:,:,1)] = Polar2Cart(app.Array1,app.ScanRadius,app.vTHETA,app.vDeltaY);
            [~,~,Z(:,:,2)] = Polar2Cart(app.Array2,app.ScanRadius,app.vTHETA,app.vDeltaY);
            [~,~,Z(:,:,3)] = Polar2Cart(app.Array3,app.ScanRadius,app.vTHETA,app.vDeltaY);
            Dimesion = app.Switch2D3D.Value;
            
            if(realTime)
            % 测试新的等高线画法。2019.06.20
                Len = m*n;
                xMin = min(min(X));
                xMax = max(max(X));
                yMin = min(min(Y));
                yMax = max(max(Y));
                x = reshape(X',1,Len);
                y = reshape(Y',1,Len);
                xi = linspace(xMin,xMax,n)';
                yi = linspace(yMin,yMax,Len*10);
                z = reshape(Z(:,:,1)',1,Len);
                [XDisp,YDisp,ZDisp(:,:,1)]=griddata(x,y,z,xi,yi,'linear');
                z = reshape(Z(:,:,2)',1,Len);
                [~,~,ZDisp(:,:,2)]=griddata(x,y,z,xi,yi,'linear');
                z = reshape(Z(:,:,3)',1,Len);
                [~,~,ZDisp(:,:,3)]=griddata(x,y,z,xi,yi,'linear');

            else
                XDisp = X;
                YDisp = Y;
                ZDisp = Z;
            end
%             cla(app.UIAxes);
            [XDisp,YDisp] = myRotate(XDisp+offsetX,YDisp+offsetY,offsetX,offsetY,app.RobotYaw);
            [X,Y] = myRotate(X+offsetX,Y+offsetY,offsetX,offsetY,app.RobotYaw);
            hold(app.UIAxes,'on');
            if(contains(Dimesion,'等高'))
                switch(app.ButtonGroup_2.SelectedObject.Text)
                    case 'CH1'
                        [~,app.preContour] = contour(app.UIAxes,XDisp,YDisp,ZDisp(:,:,1),app.CH1Threshold);
                        view(app.UIAxes,2);
                    case 'CH2'
                        [~,app.preContour]  = contour(app.UIAxes,XDisp,YDisp,ZDisp(:,:,2),app.CH1Threshold);
                        view(app.UIAxes,2);
                    case 'CH3'
                        [~,app.preContour]  = contour(app.UIAxes,XDisp,YDisp,ZDisp(:,:,3),app.CH1Threshold);
                        view(app.UIAxes,2);
                end
%                 BackGround = randn(size(ZDisp(:,:,1)))*1;
%                 contour(app.UIAxes,XDisp,YDisp,BackGround,40);
            else
                switch(app.ButtonGroup_2.SelectedObject.Text)
                    case 'CH1'
                        [~,app.preContour]  = mesh(app.UIAxes,XDisp,YDisp,ZDisp(:,:,1));
                        colormap(app.UIAxes);
                    case 'CH2'
                        [~,app.preContour]  = mesh(app.UIAxes,XDisp,YDisp,ZDisp(:,:,2));
                        colormap(app.UIAxes);
                    case 'CH3'
                        [~,app.preContour]  = mesh(app.UIAxes,XDisp,YDisp,ZDisp(:,:,3));
                        colormap(app.UIAxes);
                end
            end
            axis(app.UIAxes,'auto');
            hold(app.UIAxes,'off');
        end

        function [X,Y,Z] = applierContour(app,offsetX,offsetY)
            cla(app.UIAxes,'reset');
            X = app.GridX;
            Y = app.GridY;
            H = fspecial('average',3);
            Z(:,:,1) =imfilter(app.Array1,H,'replicate');
            Z(:,:,2) =imfilter(app.Array2,H,'replicate');
            Z(:,:,3) =imfilter(app.Array3,H,'replicate');
%             Z(:,:,1) = medfilt2(app.Array1);
%             Z(:,:,2) = medfilt2(app.Array2);
%             Z(:,:,3) = medfilt2(app.Array3);
%             [X,Y,Z] = ContourInterp(X,Y,Z);     % 为改善显示，尝试插值 2020.11.30
            Dimesion = app.Switch2D3D.Value;

%             XDisp = X;
%             YDisp = Y;
%             ZDisp = Z;
% %             cla(app.UIAxes);
%             [XDisp,YDisp] = myRotate(XDisp+offsetX,YDisp+offsetY,...
%                 offsetX,offsetY,270-app.RobotYaw);
%             [X,Y] = myRotate(X+offsetX,Y+offsetY,offsetX,offsetY,270-app.RobotYaw);
%             hold(app.UIAxes,'on');
            if(contains(Dimesion,'等高线'))
                switch(app.ButtonGroup_2.SelectedObject.Text)
                    case 'CH1'
                        [~,app.preContour] = contour(app.UIAxes,X,Y,Z(:,:,1),app.CH1Threshold);
                        view(app.UIAxes,2);
                    case 'CH2'
                        [~,app.preContour]  = contour(app.UIAxes,X,Y,Z(:,:,2),app.CH1Threshold);
                        view(app.UIAxes,2);
                    case 'CH3'
                        [~,app.preContour]  = contour(app.UIAxes,X,Y,Z(:,:,3),app.CH1Threshold);
                        view(app.UIAxes,2);
                end
            else
                switch(app.ButtonGroup_2.SelectedObject.Text)
                    case 'CH1'
                        [app.preContour]  = mesh(app.UIAxes,X,Y,Z(:,:,1));
                        colormap(app.UIAxes);
                    case 'CH2'
                        [app.preContour]  = mesh(app.UIAxes,X,Y,Z(:,:,2));
                        colormap(app.UIAxes);
                    case 'CH3'
                        [app.preContour]  = mesh(app.UIAxes,X,Y,Z(:,:,3));
                        colormap(app.UIAxes);
                end
            end
            axis(app.UIAxes,'auto');
%             hold(app.UIAxes,'off');
        end

        function setLampStatus(app)
            Status = app.SCom.Status;
            switch Status
                case 'closed'
                    app.LampStatus.Color = [1,0,0];
                    app.ButtonCom1Control.Text = '打开串口1';
                case 'open'
                    app.LampStatus.Color = [0,1,0];
                    app.ButtonCom1Control.Text = '关闭串口1';
                otherwise
            end
        end
        
        function setLampStatus2(app)
            Status = app.SCom2.Status;
            switch Status
                case 'closed'
                    app.LampStatus_2.Color = [1,0,0];
                    app.ButtonCom2Control.Text = '打开串口2';
                case 'open'
                    app.LampStatus_2.Color = [0,1,0];
                    app.ButtonCom2Control.Text = '关闭串口2';
                otherwise
            end
        end
        
        
        function [triangle_x,triangle_y,rectangle_x,rectangle_y]= trianglePlot(app,x,y,width,Alpha)
            triWidth = width/2;
            recLenth = app.EditScanRadius.Value;

            x1 = x; x2 = x1-triWidth; x3 = x1+triWidth;   % 向上
            y1 = y; y2 = y1-width;    y3 = y1-width;
            xr1 = x1-triWidth;xr2 = x1+triWidth;xr3 = x1+triWidth;xr4 = x1-triWidth;
            yr1 = y;          yr2 = y;          yr3 = y +recLenth;yr4 = y +recLenth;

            [x2, y2] = myRotate(x2,y2,x1,y1,Alpha);
            [x3, y3] = myRotate(x3,y3,x1,y1,Alpha);
            [xr1, yr1] = myRotate(xr1,yr1,x,y,Alpha);
            [xr2, yr2] = myRotate(xr2,yr2,x1,y1,Alpha);
            [xr3, yr3] = myRotate(xr3,yr3,x1,y1,Alpha);
            [xr4, yr4] = myRotate(xr4,yr4,x1,y1,Alpha);
            
            triangle_x=[x1,x2,x3,x1];
            triangle_y=[y1,y2,y3,y1];
            rectangle_x = [xr1,xr2,xr3,xr4,xr1];
            rectangle_y = [yr1,yr2,yr3,yr4,yr1];
%             fill(triangle_x,triangle_y,'r');
        end
        
        function updateTime(app)
            t = datetime('now','Format','yyyy-MM-dd''-''HH:mm:ss');
            app.EditFieldCurrentTime.Value = datestr(t,'yyyy年mm月dd日 HH:MM:SS');
        end
        
        function manualScan(app)
                % 完成五项工作：
                % 1、打开控制、数据串口；
                % 2、发送开始扫描的命令；
                % 3、接收控制串口过来的角度实时数据，更新显示；
                % 4、接收数据串口的扫描数据，实时显示；
                % 5、当停止扫描时，数据存入文件，关闭串口。
                cla(app.UIAxes);
                app.ComData = [];
                app.ComData2 = [];
                app.DispData = [];
                app.COM1DataReadable = true;   % 设置响应串口。
                
                app.CurrentAngle = 0;%app.EditCurrentAngle.Value;                 % 当前角度
                app.ScanDirection = app.SwitchOrientation.Value;                    % 扫描方向
                %             app.DeltaY = app.EditDeltaY.Value;                    % Y 轴增量
                app.EditAccumulateDeltaY.Value = app.EditAccumulateDeltaY.Value +...
                    app.EditDeltaY.Value;                    % Y 轴增量
                getScanParameter(app);
                if(strcmp(app.ScanDirection,'顺时针'))
                    app.Direction = '00';
                else
                    app.Direction = '01';
                end
%                 Index = [3 4 5 6];
%                 Value = ['55',dec2base(app.ScanVelocity,16,2),...
%                     dec2base(app.ScanAngle,16,2),app.Direction];     % num2str 等同 base2dec(A,10)
%                 Order = setControlOrder(app,Index,Value);
                
                % 在实时角度回传尚未实现前，根据扫描角度和速度，
                % 设置定时器实现自动停止。2019.05.26
    %             Delay = (pi/180*app.ScanAngle/app.ScanRadius)/(app.ScanVelocity/100);
    %             h = timer('StartDelay', Delay,  'ExecutionMode', 'singleShot',...
    %                 'TimerFcn', @app.stopScan);
    %             start(h);   %开启定时
    
                % ==================== 置串口状态为常开 2019.05.28 =================== %
    %             openControlCom(app);
    %             str = '控制串口已打开……';
    %             scanStatus(app,str);
    %             openDataCom(app);                          % 打开串口
    %             str = '数据串口已打开……';
    %             scanStatus(app,str);
                % ==================== 置串口状态为常开 END =================== %
    %             fprintf(app.SCom2,'%02X',Order);           % 发送指令，启动扫描。
%                 fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
    %             str = ['发送控制指令：',Order];
    %             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
    %             closeControlCom(app);   % 调试时，为设置串口回调   20190513
    %             str = '正在读取数据……';
    %             scanStatus(app,str);
            
        end
        
        function autoScan(app)
                % 完成五项工作：
                % 1、打开控制、数据串口；
                % 2、发送开始扫描的命令；
                % 3、接收控制串口过来的角度实时数据，更新显示；（这步已取消）
                % 4、接收数据串口的扫描数据，实时显示；（在COM1响应中实现）
                % 5、当停止单次扫描时，数据存入文件，停止串口读数；
                % （如何判断单次探扫结束？机器人从串口2发回结束状态，触发ButtonStopScan）
                % 6、向机器人发送称动指令，重复步骤1-5，直到整个区域探扫结束。
                % （如何判断区域探扫结束？机器人从串口2发回结束状态，触发ButtonContour）
                cla(app.UIAxes);
                app.ComData = [];
                app.DispData = [];
                app.COM2DataReadable = true;
                app.COM1DataReadable = true;   % 设置响应串口读数。
                
                % 向机器人发送自动探扫命令。
                OrderType = '03';
                [Lat] = LatLon2Bytes(app.EditFieldRobotLat.Value+1.5);
                [Lon] = LatLon2Bytes(app.EditFieldRobotLon.Value);
                AngleV = sprintf('%02X',app.EditScanVelocity.Value);
                Step = sprintf('%02X',app.EditDeltaY.Value*10);
                Radius = app.EditScanRadius.Value*100;
                Radius = CHValueTransform(Radius,1);
                SequenceNumber = '00';
                TotalNumber = '00';
                [Order] = controlOrder(Lon,Lat,AngleV,Step,OrderType,Radius,SequenceNumber,TotalNumber);
                fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
                % 指令发出去后，机器人自动执行探扫
             
        end
       
        function addForwardWayPoint(app)
            xDelta = (app.EditFieldX.Value-app.InitRobotLon)*app.LATLON_M;
            yDelta = (app.EditFieldY.Value-app.InitRobotLat)*app.LATLON_M;
            app.xDomain = [app.xDomain xDelta];
            app.yDomain = [app.yDomain yDelta];
            app.xRouteForward = [app.xRouteForward xDelta];
            app.yRouteForward = [app.yRouteForward yDelta];
%             lineWidth = app.RobotWidthEditField.Value/100;

            hold(app.UIAxes,'on');
            curPoint = plot(app.UIAxes,app.EditFieldX.Value,app.EditFieldY.Value,'r*');
            app.hPoint = [app.hPoint curPoint];

            hold(app.UIAxes,'off');

        end
        
        % 开发者模式下，对串口2的响应。
        function developerComCallbace2(app)
            if(~app.COM1DataReadable)
                if(app.SCom2.BytesAvailable>0)
                    fread(app.SCom2,app.SCom2.BytesAvailable,'uint8');
                end
                return;
            end
            m = 6;
            n = floor(app.SCom2.BytesAvailable/6);
            len = m*n;
            if(len>6)
                [data,~]  = fread(app.SCom2,len,'uint8');   % 读取就字符的情况
                %                 data = data*(app.Accuracy);
                data(6:6:end) = [];
                data(5:5:end) = [];
                data(2:4:end) = [];
                data(1:3:end) = [];   % 默认从7D接收的情况下，清除头尾数据。
                app.ComData2 = [app.ComData2; data];
                %                 当解决角度的实时回传后，可以用以下代码。2019.05.26
                y = angleToFloat(app,app.ComData2);
                app.CurrentAngle = app.Accuracy*y(end);
                app.EditCurrentAngle.Value = app.CurrentAngle;
%                 % 达到扫描角度，自动停止扫描。2019.05.20
%                 if(abs(y(1)-y(end))>abs(app.ScanAngle)*(1-app.Accuracy))
%                     ButtonStopScanPushed(app, event);
%                 end
            end
        end
        
        % 操作者模式下，对串口2的响应。
        function applierComCallbace2(app)
            if(~app.COM2DataReadable)    % 串口2应保持常开，只有在演示行进路线时关闭。
                if(app.SCom2.BytesAvailable>0)
                    fread(app.SCom2,app.SCom2.BytesAvailable,'uint8');
                end
                return;
            end
            
            FrameLength = 25;
            n = floor(app.SCom2.BytesAvailable/FrameLength);
            len = FrameLength*n;
            if(len>FrameLength-1)
                [data,~]  = fread(app.SCom2,len,'uint8');   % 读取就字符的情况
                [app.RobotLat,app.RobotLon,app.RobotYaw,app.RobotStatus] = ...
                    readDataFromCom2(data,app.DeltaLatA,app.DeltaLatB,FrameLength);
                % 更新位置
                if(isempty(app.RobotLat))
                    return;
                end
                app.EditFieldRobotLat.Value = app.RobotLat(end);
                app.EditFieldRobotLon.Value = app.RobotLon(end);
                app.EditFieldOriention.Value = app.RobotYaw(end);
                [x,y] = LatLon2UTM(app.RobotLat(end),...
                    app.RobotLon(end),app.InitRobotLat,app.InitRobotLon);
                app.EditRobotPosX.Value = x;
                app.EditRobotPosY.Value = y;
                app.BehindLineColor = 1;   % 画线
                currentRobotPos(app);    % 需要更新位置的。
            end
            % 处理方式同 currentRobotPos 函数
            app.EditFieldCurStatus.Value = app.RobotStatus(end);
            if(app.ScanStatus == app.RobotStatus(end))
                return;
            end
            
            app.EditFieldPreStatus.Value = app.ScanStatus;
            app.ScanStatus = app.RobotStatus(end);
            switch app.ScanStatus
                case 1
                    app.COM1DataReadable = true;     % 单次启动探扫
                    cla(app.UIAxes);
                    app.ComData = [];
                    app.DispData = [];
                    app.CurScanStatus.Value = '单次开始';
                case 2
                    stopScan(app);               % 保存文件
                    app.COM1DataReadable = false;    % 单次停止探扫
                    app.CurScanStatus.Value = '单次停止';
                case 3
    %                         app.COM1DataReadable = true;     % 区域开始探扫
                    app.CurScanStatus.Value = '区域开始';
                    app.InitRobotLon = app.EditRobotPosX.Value;
                    app.InitRobotLat = app.EditRobotPosY.Value;
                case 4
                    stopScan(app);               % 保存文件
                    app.COM1DataReadable = false;    % 区域停止探扫
                    app.CurScanStatus.Value = '区域停止';
                    msgbox('区域探扫结束，正在成像，请稍等……');
                    contourDomain(app);          % 成像。
            end
            
        end
        
        % 开发者模式下，对串口1的响应。
        function developerComCallbace1(app)
            if(strcmp(app.Equipment,'型号1'))
                if(~app.COM1DataReadable)
                    if(app.SCom.BytesAvailable>0)
                        fread(app.SCom,app.SCom.BytesAvailable,'uint8');
                    end
                    return;
                end
                
                if(contains(app.DataSourceButtonGroup.SelectedObject.Text,'实时'))      % 连接探测器时用这段代码
                    m = 8;
                    n = floor(app.SCom.BytesAvailable/32);
                    len = m*n;
                    if(len>8)
                        [data,~]  = fread(app.SCom,len,'float32');
                        data(8:8:end) = [];
                        data(1:7:end) = [];
                        app.ComData = [app.ComData data'];
                        app.DataLen = app.DataLen + len*3/4;
                        A = reshape(data,6,n);
                        x = ([A(1,:);A(2,:);A(3,:)])*5000/32768;
                        app.DispData = [app.DispData x];
                    end
                    y = app.DispData;
                    setRealTimePlot(app,y',app.DataLen);
                else          % 虚拟串口调试时用这段代码。
                    m = 6;
                    n = floor(app.SCom.BytesAvailable/24);
                    len = m*n;
                    if(len>6)
                        [data,~]  = fread(app.SCom,len,'float32');
                        app.ComData = [app.ComData data'];
                        app.DataLen = app.DataLen + len;
                        A = reshape(data,6,n);
                        x = ([A(1,:);A(2,:);A(3,:)])*5000/32768;
                        app.DispData = [app.DispData x];
                    end
                    y = app.DispData;
                    setRealTimePlot(app,y',app.DataLen);
                end
            else   % 型号2 
                if(~app.COM1DataReadable)
                    if(app.SCom.BytesAvailable>0)
                        fread(app.SCom,app.SCom.BytesAvailable,'char');
                    end
                    return;
                end
                
                if(contains(app.DataSourceButtonGroup.SelectedObject.Text,'实时'))      % 连接探测器时用这段代码
                    len = app.SCom.BytesAvailable;
                    if(len>0)                        
                        [data,~]  = fread(app.SCom,len,'char');
                        data = str2double(char(data'));
                        [Column,~] = size(data);
                        app.ComData = [app.ComData;data];
                        app.DataLen = app.DataLen + Column;
                        A = zeros(2,Column);
                        A(1,:) = data(1:Column);
                        A(2,:) = data(1+Column:end);
                        x = ([A(1,:);A(2,:)]);
                        app.DispData = [app.DispData x];
                    end
                    y = app.DispData;
                    setRealTimePlot(app,y',app.DataLen);
                else          % 虚拟串口调试时用这段代码。
                    m = 6;
                    n = floor(app.SCom.BytesAvailable/24);
                    len = m*n;
                    if(len>6)
                        [data,~]  = fread(app.SCom,len,'float32');
                        app.ComData = [app.ComData data'];
                        app.DataLen = app.DataLen + len;
                        A = reshape(data,6,n);
                        x = ([A(1,:);A(2,:);A(3,:)])*5000/32768;
                        app.DispData = [app.DispData x];
                    end
                    y = app.DispData;
                    setRealTimePlot(app,y',app.DataLen);
                end
            end
        end
        
        % 操作者模式下，对串口1的响应。
        function applierComCallbace1(app)
            if(~app.COM1DataReadable)
                if(app.SCom.BytesAvailable>0)
                    fread(app.SCom,app.SCom.BytesAvailable,'uint8');
                end
                return;
            end
            
            FrameLength = 32;   % 应用2019.12.29修改的协议。
            n = floor(app.SCom.BytesAvailable/FrameLength);
            len = FrameLength*n;
            if(len>FrameLength-1)
                [data,~]  = fread(app.SCom,len,'uint8');   % 读取就字符的情况
                [Value1,Value2,Value3,Angle,Radius,Height] = readDataFromCom1(data,FrameLength);
                % ------------ 20191230 以半径和角度表示探扫头的坐标 ------------ 
                Angle = Angle*1e-2;   % 借用的LatLonTransform 是1e9倍。
                Radius = Radius*1e-2;
                Height = Height*1e-2;
                UTMN1 = Radius.*sin(Angle*pi/180);   % 相对位置
                UTME1 = Radius.*cos(Angle*pi/180);
%                 [Lat,Lon] = UTM2LatLon(UTME1,UTMN1,app.RobotLat0,app.RobotLon0);
                % ------------ 20191230 更新了探扫头的坐标表示法END ------------ 
                
                % ------------ 20200102 以经纬度表示探扫头的坐标 ------------ 
%                 Lon = Radius;    % 又按回传 GPS 修改代码
%                 Lat = Angle;     % 又按回传 GPS 修改代码
%                 tmp = [Value1,Value2,Value3,UTME1,UTMN1,app.EditRobotPosX.Value,...
%                     app.EditRobotPosY.Value,app.RobotYaw];
%                 app.ComData = [app.ComData;tmp];
                % ------------ 20200102 以经纬度表示探扫头的坐标END ------------ 
%                 tmp = [Value1,Value2,Value3,Lat,Lon];     % 以绝对位置保存1倍
                % ------------ 20200104 文件规范 ------------ 
                % 每一次探扫的串口1和串口2 的数据保存为 app.ComData，
                % 多次探后，成像时多次 app.ComData 保存为AreaData。
                % app.ComData格式：V1、V2、V3、UTME1、UTMN1、offsetX、offsetY、RobotYaw
                [m,n] = size(Value1);
%                 offsetX = repmat(app.EditRobotPosX.Value,m,n);  % GPS有漂移，
%                 offsetY = repmat(app.EditRobotPosY.Value,m,n);
                offsetX = repmat(app.EditRobotPosX.Value,m,n);  % 区域探扫开始时的坐标为原点。
                offsetY = repmat(app.EditRobotPosY.Value,m,n);
                if(isempty(app.RobotYaw))
                    app.RobotYaw = app.RobotYawPro;
                else
                    app.RobotYawPro = app.RobotYaw;
                end
                Yaw = repmat(app.RobotYaw(end),m,n);
                tmp = [Value1,Value2,Value3,UTME1,UTMN1,offsetX,offsetY,Yaw];
                app.ComData = [app.ComData;tmp];
                % ------------ 20200104 文件规范END ------------ 
                % 更新位置。在处理速度跟不上的情况下，这一段可以省略，不实时显示探测器坐标。20200113
%                 [UTME,UTMN] = LatLon2UTM(Lat,Lon,app.RobotLat0,app.RobotLon0);
                UTME = UTME1 + app.EditRobotPosX.Value;
                UTMN = UTMN1 + app.EditRobotPosY.Value;
                [UTME, UTMN] = myRotate(UTME(end), UTMN(end),...
                    offsetX(end),offsetY(end),Yaw(end)-270);
                app.EditFieldSensorPosX.Value = UTME1(end);
                app.EditFieldSensorPosY.Value = UTMN1(end);
                
                x = ([Value1,Value2,Value3])*5000/32768;
                app.DispData = [app.DispData;x];
                app.EditScanRadius.Value = Radius(end)*100;
                app.EditCurrentAngle.Value = Angle(end);
                app.EditScanHeight.Value = Height(end);
                app.Angle4Debug = [app.Angle4Debug; Angle];
            end
            setRealTimePlot(app,app.DispData,app.DataLen);
        end
        
        function currentRobotPos(app)
            offsetX = app.EditRobotPosX.Value;
            offsetY = app.EditRobotPosY.Value;
            app.xRouteBehind = [app.xRouteBehind offsetX];
            app.yRouteBehind = [app.yRouteBehind offsetY];
            lineWidth = app.RobotWidthEditField.Value/100;


            if(~isempty(app.hTriangle))
                delete(app.hTriangle);
                delete(app.hRectangle);
            end

            hold(app.UIAxes,'on');
            [triangle_x,triangle_y,rectangle_x,rectangle_y] = trianglePlot(app,offsetX,offsetY,lineWidth,270-app.RobotYaw(end));
            app.hTriangle = fill(app.UIAxes,triangle_x,triangle_y,[0 0 1]);
            if(app.bRectangle)
                app.hRectangle= fill(app.UIAxes,rectangle_x,rectangle_y,[0.94 0.78 0.78]);
            end
            
            if(length(app.xRouteBehind)>1)
                switch app.BehindLineColor
                    case 1
                        app.hLineBehind = [app.hLineBehind line(app.UIAxes,app.xRouteBehind,app.yRouteBehind,...
                    'LineWidth',2)];
                    case 2
                        app.hLineBehind = [app.hLineBehind line(app.UIAxes,app.xRouteBehind,app.yRouteBehind,...
                    'LineWidth',2,'Color','red')];
                end
                
            end

            hold(app.UIAxes,'off');
%             set(app.UIAxes,'DataAspectRatio',[1 1 1]);
        end
        
        function startScan(app)
            cla(app.UIAxes,'reset');
            value = app.SwitchScanMethod.Value;
            app.ButtonStopScan.Enable = 'on';
            app.COM1DataReadable = true;   % 设置响应串口。
            app.COM2DataReadable = true;   % 设置响应串口。
            if(contains(value,'自动'))
                app.SwitchOrientation.Enable = 'off';
                autoScan(app);

            else    % 手动操作探扫的情况。
                app.SwitchOrientation.Enable = 'on';
                manualScan(app);
            end            
        end

        function stopScan(app)
            %             msgbox('定时器已启动');
            app.ButtonStopScan.Enable = 'off';
            app.COM1DataReadable = false;   % 设置响应串口。
            app.DispData = [];
%             Index = [3];
%             Value = ['44'];
%             Order = setControlOrder(app,Index,Value);
%             %             openControlCom(app);
%             %             fprintf(app.SCom2,'%02X',Order);
%             fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
            pause(0.2);
% %             str = ['发送控制指令：',Order];
% %             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
%             closeControlCom(app);
% %             str = '控制串口已关闭……';
% %             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
%             closeDataCom(app);
%             str = '数据串口已关闭……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
            
            DataFileName = saveData(app);
%             app.AreaDataFile(length(app.AreaDataFile)+1).filename = DataFileName;
            app.AreaDataFile(length(app.AreaDataFile)+1).filename =...
                [pwd,'\proData\',DataFileName];
%             delete(timerfindall);
%             ButtonStopScanPushed(app, event);
        end
        
        function prepareScan(app)
%             Index = [3];
%             Value = ['22'];
%             Order = setControlOrder(app,Index,Value);
%             openControlCom(app);
%             str = '控制串口已打开……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
% %             fprintf(app.SCom2,'%02X',Order);
%             fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
% %             str = ['发送控制指令：',Order];
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
%             closeControlCom(app);
%             str = '控制串口已关闭……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
            % ==================== 置串口状态为常开 2019.05.28 =================== %
            app.offsetX0 = app.EditRobotPosX.Value;
            app.offsetY0 = app.EditRobotPosY.Value;
            app.COM1DataReadable = false;
            app.AreaDataFile = []; % 每一次成像后，清除本次数据。
%             openControlCom(app);
%             str = '控制串口已打开……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
            openDataCom(app);
%             str = '数据串口已打开……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
            setLampStatus(app);
%             setLampStatus2(app);
            app.ButtonStartScan.Enable = 'on';
            % ==================== 置串口状态为常开 END =================== %
            
        end
        
        function contourDomain(app)
            cla(app.UIAxes);
            ButtonName = questdlg('请选择区域成像的数据来源。', ...
                '请选择区域成像的数据来源。', '实时数据', '文件数据', '实时数据');
            app.realTime = 1;
            
            TargetMaxWidth = app.EditMinSpan.Value/100;
            v = app.EditScanVelocity_2.Value;
            fs = app.EditFs.Value;
            ScanLineRange = app.EditScanRadius.Value*app.EditScanAngle.Value*pi/180;
            MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);    % 每次扫描目标个数上限。
            SignalWidth = 2*TargetMaxWidth/v*fs;
%             if(~isempty(app.preContour))
%                 delete(app.preContour);
%             end
            if(contains(ButtonName,'实时'))    % 按实时数据按钮的情况。
                % ==================== 置串口状态为常开 2019.05.28 =================== %
%                 closeControlCom(app);
%             str = '控制串口已关闭……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
                closeDataCom(app);
%             str = '数据串口已关闭……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
                app.COM2DataReadable = false;      % 设置是否响应串口2
                setLampStatus(app);
%                 setLampStatus2(app);
                app.ButtonStartScan.Enable = 'off';
                % ==================== 置串口状态为常开 END =================== %
                
                % 数据重置。
                data = app.AreaData;
                app.SN = 1;            % 在某一区域的第几次扫描数据
                app.AreaData = [];     % 在某一区域的扫描数据缓冲区
                app.EditAccumulateDeltaY.Value = 0;
                app.AccumulateDeltaY = 0;
                t = datetime('now','Format','yyyy-MM-dd''-''HHmmss');
                app.ContourFile = strcat(pwd,'\proData\',datestr(t,'mmdd-HHMMSS'),'.mat');    % 用一个全局变量保存，便于定位时查找数据。
                save(app.ContourFile,'data','-mat');
                AngleFileName = strcat(pwd,'\proData\',datestr(t,'角度mmdd-HHMMSS'),'.txt');
                fileID = fopen(AngleFileName,'w');
                fprintf(fileID,'%12s\n','探扫角度');
                fprintf(fileID,'%6.2f\n',app.Angle4Debug);
                fclose(fileID);
                app.Angle4Debug = [];
            
                if(strcmp(app.MenuVersionApplier.Checked,'on'))   % 机器人探扫时，数据存储格式不同
%                     [app.Array1,app.Array2,app.Array3,app.GridX,app.GridY] = ...
%                         dataFromComtoArray3(data,app.RobotLat0,app.RobotLon0);
                    [app.Array1,app.Array2,app.Array3,app.GridX,app.GridY] = ...
                        dataFromComtoArray4(data);
                else
                    [app.Array1,app.Array2,app.Array3,~,Angle,AccumDeltaY] = ...
                        dataFromComtoArray2(data,SignalWidth,MaxTargetNum);
                    app.vTHETA = pi/180*(Angle);
                    app.vDeltaY = AccumDeltaY*0.01;
                end
            elseif(contains(ButtonName,'文件'))    % 按文件数据按钮的情况。
                [app.Array1,app.Array2,app.Array3,app.AreaDataFile] = ...
                    dataFromFiletoArray(SignalWidth,MaxTargetNum);
                app.vTHETA = linspace(3*pi/4,pi/4,size(app.Array1,2));
                app.vDeltaY = linspace(0,1.5,size(app.Array1,1));
                app.realTime = 0;
            end       

            if(strcmp(app.MenuVersionApplier.Checked,'on'))    % 机器人探扫时，数据存储格式不同
                [app.X,app.Y,app.Z] = applierContour(app,app.offsetX0,app.offsetY0);
            else
                app.ScanRadius = app.EditScanRadius.Value;
                [app.X,app.Y,app.Z] = developerContour(app,app.realTime,app.EditRobotPosX.Value,...
                    app.EditRobotPosY.Value);    % 提取X,Y,Z，用于定位和识别。2019.06.09
            end
        end
        
        function setRecoginizeResult(app,Row)
            load(app.ContourFile);   % 成像时保存的文件，存储了区域内探扫的数据
            N = app.TargetInArrayRow(Row)+1;   % 选中的目标对应第N次探扫，文件中保存在第N+1位置。
            dat = data{1,N}.Value(:,1:3);
            results = targetRecognizeInTable(dat,app.FileCoef,app.FileSettings);      % 进入识别函数。
            app.UITable.Data{Row,4} = results;
            set(app.TextHandle(Row),'visible','off');
            app.TextHandle(Row) = ... results{1}; % 
                text(app.UIAxes,app.XPos(Row),app.YPos(Row),results{1});
%             FullName = app.AreaDataFile(Row).fullname;
%             FileName = app.AreaDataFile(Row).filename;
%             TargetMaxWidth = app.EditMinSpan.Value/100;
%             v = app.EditScanVelocity_2.Value;
%             fs = app.EditFs.Value;
%             ScanLineRange = app.EditScanRadius.Value*app.EditScanAngle.Value*pi/180;
%             MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);    % 每次扫描目标个数上限。
%             SignalWidth = 2*TargetMaxWidth/v*fs;
%             results = targetRecognize4Table(app,FullName,FileName,...
%                 SignalWidth,MaxTargetNum);   % 进入识别函数。
%             app.UITable.Data{Row,4} = results;
%             set(app.TextHandle(Row),'visible','off');
%             app.TextHandle(Row) = ... results{1}; % 
%                 text(app.UIAxes,app.XPos(Row),app.YPos(Row),results{1});
        end
        
        function plotCurve(app,Filename)
            fid = fopen(Filename);
            if(fid == -1)
                error(['读取文件 ',Filename,' 错误。']);
            end
            fseek(fid,0,'eof');
            filelength = ftell(fid);
            fseek(fid,0,'bof');
            [A,count]=fread(fid,(filelength)/4,'float32');
            fclose(fid);
            A = reshape(A,6,count/6);
            x = ([A(1,:);A(2,:);A(3,:)])*5000/32768;
            setRealTimePlot(app,x,0);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center'); 
            app.UIFigure.Name = '目标定位与识别系统实操应用版--型号1';
            app.Timer = timer('TimerFcn',@(~,~)updateTime(app),'Period',1,'ExecutionMode','fixedRate');
%             start(app.Timer);

        end

        % Menu selected function: MenuExit
        function MenuExitSelected(app, event)
            UIFigureCloseRequest(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(instrfindall);
%             stop(app.Timer);
%             delete(app.Timer);
            delete(app)
        end

        % Callback function
        function ButtonBPSettingsPushed(app, event)
            TrainSettings(app);
        end

        % Callback function
        function ButtonBPTrainPushed(app, event)
            %             ax = app.UIAxes;
            %             tb = axtoolbar(ax,{'zoomin','zoomout','datacursor','restoreview'});
            BPTrainMain(app);
            
        end

        % Callback function
        function ButtonBPCoefSavePushed(app, event)
            [filename,pathname] = uiputfile( {'*.mat';'*.*'}, '保存神经网络权值');
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            Coef = app.Coef;
            save([pathname,filename],'Coef');
            msgbox('神经网络权值保存成功！');
        end

        % Callback function
        function ButtonSingleTargetRecognizePushed(app, event)
            if(isempty(app.FileCoef))
                msgbox('请载入权值文件');
                ButtonCoefLoadPushed(app, event);
            end
            
            % % 选择数据文件路径
            FolderName = uigetdir('..\OriginDat','请选择数据文件路径。');
            % FolderName = 'E:\matlab\OriginDat\20190305';    % 调试时用这个默认路径。
            if(isequal(FolderName,0))
                errordlg('载入文件失败');
                return;
            end
            A = dir(fullfile(FolderName,'*.dat'));
            % %列表选择对话框
            AllFileNames = cell(size(A));
            for i=1:size(A,1)
                AllFileNames{i,1} = A(i,1).name;
            end
            [Sel,Ok]=listdlg('liststring',AllFileNames,...
                'listsize',[360 300],'OkString','确定','CancelString','取消',...
                'promptstring','请选择数据文件','name','请选择数据文件（多选）',...
                'selectionmode','single');
            if(Ok == 0)
                msgbox('您取消了选择文件。');
                return;
            end
            FileName = A(Sel,1);
            FullName = A(Sel,1);
            FileNumSel = size(FileName,1);
            for k=1:FileNumSel
                FullName(k,1).name = strcat(FolderName,'\', FileName(k,1).name);
            end
            
            set(app.UITable, 'Data', []);
            h = waitbar(0,'正在识别...');
            TargetMaxWidth = app.EditMinSpan.Value/100;
            v = app.EditScanVelocity_2.Value;
            fs = app.EditFs.Value;
            ScanLineRange = app.EditScanRadius.Value*app.EditScanAngle.Value*pi/180;
            MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);    % 每次扫描目标个数上限。
            SignalWidth = 2*TargetMaxWidth/v*fs;
            [~,TableDataNoChecked] = targetRecognize(app,FullName,FileName,...
                SignalWidth,MaxTargetNum);   % 进入识别函数。
            TableColumnName = {'序号','文件名','识别结果'};
            setTableView(app,TableColumnName,TableDataNoChecked);
            waitbar(1,h,'识别完成...');
            app.EditFieldTargetType.Value = TableDataNoChecked.results{1,1};
            close(h);

        end

        % Button pushed function: ButtonMultiTargetRecognize
        function ButtonMultiTargetRecognizePushed(app, event)
            if(isempty(app.FileCoef))
                msgbox('请载入权值文件');
                ButtonCoefLoadPushed(app, event);
            end
            app.AreaDataFile = [];
            app.bCheckEnable = false;
            % % 选择数据文件路径
            FolderName = uigetdir('..\OriginDat','请选择数据文件路径。');
            if(isequal(FolderName,0))
                errordlg('载入文件失败');
                return;
            end
            % FolderName = 'E:\matlab\OriginDat\20190305';    % 调试时用这个默认路径。
            A = dir(fullfile(FolderName,'*.dat'));
            % %列表选择对话框
            AllFileNames = cell(size(A));
            for i=1:size(A,1)
                AllFileNames{i,1} = A(i,1).name;
            end
            [Sel,Ok]=listdlg('liststring',AllFileNames,...
                'listsize',[360 500],'OkString','确定','CancelString','取消',...
                'promptstring','请选择数据文件','name','请选择数据文件（多选）',...
                'selectionmode','multiple');
            if(Ok == 0)
                msgbox('您取消了选择文件。');
                return;
            end
            FileName = A(Sel,1);
            FullName = A(Sel,1);
            FileNumSel = size(FileName,1);
            for k=1:FileNumSel
                FullName(k,1).name = strcat(FolderName,'\', FileName(k,1).name);
                app.AreaDataFile(length(app.AreaDataFile)+1).filename = FileName(k,1).name;
                app.AreaDataFile(length(app.AreaDataFile)+1).fullname = FullName(k,1).name;
            end
            
            set(app.UITable, 'Data', []);

            TargetMaxWidth = app.EditMinSpan.Value/100;
            v = app.EditScanVelocity_2.Value;
            fs = app.EditFs.Value;
            ScanLineRange = app.EditScanRadius.Value*app.EditScanAngle.Value*pi/180;
            MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);    % 每次扫描目标个数上限。
            SignalWidth = 2*TargetMaxWidth/v*fs;
            [TableData,~] = targetRecognize(app,FullName,FileName,...
                SignalWidth,MaxTargetNum);   % 进入识别函数。
            TableColumnName = {'序号','文件名','选中？','识别结果'};
            setTableView(app,TableColumnName,TableData);

        end

        % Button pushed function: ButtonStartScan
        function ButtonStartScanPushed(app, event)
            startScan(app);            
        end

        % Menu selected function: MenuComSettings
        function MenuComSettingsSelected(app, event)
            ComSettings(app);
        end

        % Button pushed function: ButtonContour
        function ButtonContourPushed(app, event)
            contourDomain(app);           
        end

        % Menu selected function: MenuLPFSettings
        function MenuLPFSettingsSelected(app, event)
            LPFSettings;
        end

        % Callback function
        function ButtonResetPushed(app, event)
%             Index = [3];
%             Value = ['33'];
%             Order = setControlOrder(app,Index,Value);
%             openControlCom(app);
%             fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
%             closeControlCom(app);
            createControlCom(app)
            openControlCom(app);

            closeControlCom(app);

        end

        % Button pushed function: ButtonPrepareScan
        function ButtonPrepareScanPushed(app, event)
            prepareScan(app);            
        end

        % Button pushed function: ButtonStopScan
        function ButtonStopScanPushed(app, event)
            stopScan(app);
%             app.ButtonStopScan.Enable = 'off';
%             Index = [3];
%             Value = ['44'];
%             Order = setControlOrder(app,Index,Value);
% %             openControlCom(app);
% %             fprintf(app.SCom2,'%02X',Order);
%             fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
%             pause(0.1);
% %             str = ['发送控制指令：',Order];
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
            
            % ==================== 置串口状态为常开 2019.05.28 =================== %
%             app.COM1DataReadable = false;   % 不再响应串口。2019.05.28
%             closeControlCom(app);
%             str = '控制串口已关闭……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
%             closeDataCom(app);
%             str = '数据串口已关闭……';
%             scanStatus(app,str);            % ------------ 控制串口暂时不能关闭 2019.05.15 --------------- %
        % ==================== 置串口状态为常开 END =================== %
%             saveData(app);
            %             delete(instrfindall);
            
        end

        % Value changed function: EditScanRadius
        function EditScanRadiusValueChanged(app, event)
            value = app.EditScanRadius.Value;
            app.ScanRadius = value;
            v = value*pi/180*app.ScanVelocity.Value;
            app.EditScanVelocity_2.Value = v;
            
        end

        % Value changed function: EditScanVelocity
        function EditScanVelocityValueChanged(app, event)
            value = app.EditScanVelocity.Value;
            app.ScanVelocity = value;
            v = value*pi/180*app.EditScanRadius.Value;
            app.EditScanVelocity_2.Value = v;
            
        end

        % Callback function: ButtonGroup_2, Switch2D3D
        function Switch2D3DChanged(app, event)
            switch app.MenuVersionDeveloper.Checked
                case 'on'
                    developerContour(app,app.realTime,app.EditRobotPosX.Value,...
                        app.EditRobotPosY.Value);
                case 'off'  % 实操应用版本，读取机器人位置和扫描就绪、扫描停止指令
                    applierContour(app,app.offsetX0,app.offsetY0);
                otherwise
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            switch value
                case '高'
                    app.CH1Threshold = 20;
                case '中'
                    app.CH1Threshold = 50;
                case '低'
                    app.CH1Threshold = 100;
                otherwise
            end
        end

        % Button pushed function: ButtonPosition
        function ButtonPositionPushed(app, event)
            TargetMaxWidth = app.EditMinSpan.Value/100;
            v = app.EditScanVelocity_2.Value;
            fs = app.EditFs.Value;
            ScanLineRange = app.EditScanRadius.Value*app.EditScanAngle.Value*pi/180;
            MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);    % 每次扫描目标个数上限。
            SignalWidth = 2*TargetMaxWidth/v*fs;
            % 计算坐标、标注、更新列表。
            [app.XPos, app.YPos, app.TargetInArrayRow, app.TargetInArrayColumn] = targetPosition(app.X,app.Y,app.Z,app.CH1Threshold,...
                MaxTargetNum,SignalWidth);   %　Z 是三个通道的数据，定位只要1通道数据即可。
            x = app.XPos;
            y = app.YPos;
            if(isempty(x))
                return;
            end

            app.TextHandle = [];
            for i = 1:length(x)
%                 app.TextHandle(i) = text(app.UIAxes,x(i),y(i),[num2str(i),' 号目标(',...
%                     sprintf('%2.2f',x(i)),',',sprintf('%2.2f',y(i)),')']);
                app.TextHandle(i) = text(app.UIAxes,x(i),y(i),['目标',num2str(i)]);
            end
            %% 可以把目标对应的数据文件找出来。
            n = zeros(size(app.XPos));
            [mx,nx]=size(app.X);ax = reshape(app.X',1,mx*nx);
            [my,ny]=size(app.Y);ay = reshape(app.Y',1,my*ny);
            a = [ax;ay];
            for i = 1:length(app.XPos)   % 每一个目标对应一个文件。
                b = [app.XPos(i);app.YPos(i)];
                b = repmat(b,1,size(a,2));
                [~,I] = min(sum((a-b).^2));
                n(i) = ceil(I/nx);
            end

            %% 把判别结果输出到主界面函数，再由主界面的函数来实现显示。
            % 在Table中显示
            set(app.UITable, 'Data', []);
            number = (1:length(x))';
            coordinate = cell(length(x),1);
            for i = 1:length(x)
                coordinate{i,1} = [sprintf('(%2.2f',x(i)),',',sprintf('%2.2f',y(i)),')'];
            end
            results = cell(length(x),1);
            files = cell(length(x),1);
            res = false(1,length(x));
            % 实扫时，保存文件可以生成对应文件
%             for i = 1:length(x)
%                 results{i,1} = res(i);
%                 files{i,1} = app.AreaDataFile(n(i)).filename;
%             end
%             TableColumnName = {'序号','位置','选中？','目标对应文件'};
%             TableData = table(number,cellstr(coordinate),results,files);

            for i = 1:length(x)
                results{i,1} = res(i);
                files{i,1} = '识别';
            end
            TableColumnName = {'序号','位置','选中？'};
            TableData = table(number,cellstr(coordinate),results,files);
            
            setTableView(app,TableColumnName,TableData);
%             app.AreaDataFile = []; % 每一次成像后，清除本次数据。
            

        end

        % Button pushed function: ButtonCurrentPos
        function ButtonCurrentPosPushed(app, event)
                       app.BehindLineColor = 1;   % 画线
                       currentRobotPos(app);
        end

        % Value changed function: DropDownDirection
        function DropDownDirectionValueChanged(app, event)
%             app.RobotYaw = 0;
            value = app.DropDownDirection.Value;
            if(contains(value,'Y轴正向'))
                app.RobotYaw = 270;    % 转换成航向角
            elseif(contains(value,'X轴负向'))
                app.RobotYaw = 180;
            elseif(contains(value,'Y轴负向'))
                app.RobotYaw = 90;
            elseif(contains(value,'X轴正向'))
                app.RobotYaw = 0;
            end   
            app.BehindLineColor = 1;   % 画线
            currentRobotPos(app);
            
        end

        % Button pushed function: ButtonDomain
        function ButtonDomainPushed(app, event)
            X = app.xDomain;
            Y = app.yDomain;
            app.xPolygon = [X,X(1)];
            app.yPolygon = [Y,Y(1)];
            cla(app.UIAxes);
            fill(app.UIAxes,app.xPolygon,app.yPolygon,[.88 0.77 0.66]);
            app.xDomain = [];
            app.yDomain = [];
            app.bCaptureCurrentPoint = false;
        end

        % Button pushed function: ButtonAddVertex
        function ButtonAddVertexPushed(app, event)
            if(contains(app.SwitchCoordinateType.Value,'经纬度'))
                xDelta = (app.EditFieldX.Value-app.InitRobotLon)*app.LATLON_M;
                yDelta = (app.EditFieldY.Value-app.InitRobotLat)*app.LATLON_M;
            else
                xDelta = app.EditFieldX.Value;
                yDelta = app.EditFieldY.Value;
            end
            
            app.xDomain = [app.xDomain xDelta];
            app.yDomain = [app.yDomain yDelta];
            hold(app.UIAxes,'on');
            plot(app.UIAxes,xDelta,yDelta,'b*')
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            hold(app.UIAxes,'off');
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            Row = indices(1);
            Column = indices(2);
            
            if(~app.bCheckEnable)
                return;
            end
            if(isequal(Column,3))   % 更新选中状态
                app.UITable.Data{Row,Column} = {~app.UITable.Data{Row,Column}{1}};
            elseif(isequal(Column,4))    % 识别当前目标，并更新表格和坐标显示
                setRecoginizeResult(app,Row);
            end
            app.bCheckEnable = true;

        end

        % Value changed function: SwitchEquipment
        function SwitchEquipmentValueChanged(app, event)
            value = app.SwitchEquipment.Value;
            app.UIFigure.Name = strcat('目标定位与识别系统--', value);
        end

        % Button pushed function: ButtonDomainClear
        function ButtonDomainClearPushed(app, event)
            cla(app.UIAxes,'reset');
            app.xDomain = [];
            app.yDomain = [];
            app.bCaptureCurrentPoint = false;

        end

        % Button pushed function: ButtonTableExport
        function ButtonTableExportPushed(app, event)
            a = table2cell(app.UITable.Data);
            [file,path] = uiputfile(strcat(pwd,'\proData\表格数据.xls'));
            if(isequal(file,0) || isequal(path,0)) 
                return; 
            end
            [SUCCESS,~] = xlswrite([path,file],a);          % 写入excel文件。
            if(SUCCESS~=1)
                msgbox('请确认 xls 已关闭后再试。');
            else
                msgbox('数据保存成功！');
            end

        end

        % Button pushed function: ButtonImageRecognize
        function ButtonImageRecognizePushed(app, event)
            h = waitbar(0,'正在识别...');
            load (app.ImageLib);
            img = imread(app.ImagePath);
            [labelIdx, ~] = predict(categoryClassifier, img);
            str = categoryClassifier.Labels(labelIdx);
            waitbar(1,h,'识别完成...');
            app.EditFieldImageType.Value = str{1};
            ImageSource = strcat(pwd,'\Settings\',str{1},'.jpg');
            app.EditFieldIntroduce.Value = app.Introduce{labelIdx};
            img1 = imread(ImageSource);
            imshow(img1,'Parent',app.UIAxes2);
            close(h);
        end

        % Button pushed function: ButtonImageLoad
        function ButtonImageLoadPushed(app, event)
            cla(app.UIAxes,'reset');
            [filename,pathname] = uigetfile( {'*.jpg';'*.*'}, '载入目标图片');
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            app.ImagePath = [pathname,filename];
            app.EditFieldImageLoad.Value = app.ImagePath;
            img = imread(app.ImagePath);
            imshow(img,'Parent',app.UIAxes);
            
            app.EditFieldImageType.Value = '';
            ButtonImageRecognizePushed(app,event);

        end

        % Button pushed function: ButtonImageExport
        function ButtonImageExportPushed(app, event)
            FolderName = uigetdir(strcat(pwd,'\TrainImage'),'请选择数据文件路径。');
            if(isequal(FolderName,0))
                errordlg('图片导出失败');
                return;
            end
            movefile(app.ImagePath,FolderName);
            msgbox('图片导出成功！');

        end

        % Callback function
        function ButtonImageTrainPushed(app, event)
            h = msgbox('正在训练，需要几分钟，训练结束本对话框将自动消失。');
            [categoryClassifier, confMatrix] = ImageCategoryClassification;
            close(h);
            TableColumnName = categoryClassifier.Labels;
            TableData = table(num2cell(confMatrix,3));
            setTableView(app,TableColumnName,TableData);

            ButtonName = questdlg('训练结果保存', ...
                '是否保存本次训练结果？', '是', '否', '是');
            if(ButtonName == '否')
                return;
            end
            [file,path] = uiputfile(strcat(pwd,'\Settings\categoryClassifierMat.mat'),...
                '*.mat','categortyClassificationMat');
            if(isequal(file,0) || isequal(path,0)) 
                return; 
            end
            save([path,file],'categoryClassifier');            
        end

        % Callback function
        function MenuDatTrainSelected(app, event)
            set(app.UITable, 'Data', []);
            BPTrainMain(app);
        end

        % Button pushed function: ButtonTableDataDelete
        function ButtonTableDataDeletePushed(app, event)
            check = cell2mat(app.UITable.Data{:,3});
            [I,~] = find(check);
            app.UITable.Data(I,:) = [];
            [m,~] = size(app.UITable.Data);
            if(m>0)
                app.UITable.Data{:,1} = (1:m)';
            end
            
            x = zeros(1,m);
            y = zeros(1,m);
            % 更新目标的坐标
            for i = 1:m
                a = cell2mat(app.UITable.Data{i,2});
                b = replace(a,'(',''); 
                b = replace(b,')','');
                b = str2num(replace(b,',',' '));
                x(i) = b(1);
                y(i) = b(2);
            end
            app.XPos = x;
            app.YPos = y;
            
            if(length(app.TextHandle)>1)
                for i = 1:length(app.TextHandle)
                    set(app.TextHandle(i),'visible','off');
                end
            end
            for i = 1:m
%                 app.TextHandle(i) = text(app.UIAxes,x(i),y(i),[num2str(i),' 号目标(',...
%                     sprintf('%2.2f',x(i)),',',sprintf('%2.2f',y(i)),')']);
                app.TextHandle(i) = text(app.UIAxes,x(i),y(i),[num2str(i),' 号目标']);
            end

        end

        % Value changed function: SwitchScanMethod
        function SwitchScanMethodValueChanged(app, event)
            value = app.SwitchScanMethod.Value;
            if(contains(value,'自动'))
                app.SwitchOrientation.Enable = 'off';
            else
                app.SwitchOrientation.Enable = 'on';
            end
        end

        % Button pushed function: ButtonAddVertex_2
        function ButtonAddVertex_2Pushed(app, event)
            cla(app.UIAxes,'reset');
            axis(app.UIAxes,[app.EditRobotPosX.Value-3,app.EditRobotPosX.Value+3,...
                app.EditRobotPosY.Value-3,app.EditRobotPosY.Value+3]);
            app.bCaptureCurrentPoint = true; % 在坐标区点击有效    
            app.nAddVertex = 1; % 是否增加顶点？还是选定前进点？ 
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if(app.bCaptureCurrentPoint)
                currPt = app.UIAxes.CurrentPoint;
                xp = currPt(1,1);
                yp = currPt(1,2);
                if xp < app.UIAxes.XLim(1,1)||...
                        xp > app.UIAxes.XLim(1,2)||...
                        yp < app.UIAxes.YLim(1,1)||...
                        yp > app.UIAxes.YLim(1,2)
                    app.EditFieldX.Value = 0;
                    app.EditFieldY.Value = 0;
                    return
                end
                % 当鼠标离开坐标区时，X,Y编辑框显示为0
                app.EditFieldX.Value = xp;
                app.EditFieldY.Value = yp;
                % 选择在坐标区内点击鼠标的功能，1为增加顶点，2为前进点，3为目标选择。
                switch(app.nAddVertex)
                    case 1
                        ButtonAddVertexPushed(app, event);
                    case 2
                        addForwardWayPoint(app);
                    case 3
                    otherwise
                end
            else
                return;
            end               
        end

        % Button pushed function: ButtonManualWayPoint
        function ButtonManualWayPointPushed(app, event)
            app.nAddVertex = 2;
            app.bCaptureCurrentPoint = true;
            app.xRouteForward = app.EditRobotPosX.Value;
            app.yRouteForward = app.EditRobotPosY.Value;
            app.bAutoWayPoint = false;
            % 当前位置为出发点
            
            if(~isempty(app.hPoint))
                delete(app.hPoint);
            end
            if(~isempty(app.hLineBehind))
                delete(app.hLineBehind);
            end
            hold(app.UIAxes,'on');
            curPoint= plot(app.UIAxes,app.EditRobotPosX.Value,app.EditRobotPosY.Value,'r*');
            app.hPoint = [app.hPoint curPoint];

            hold(app.UIAxes,'off');

        end

        % Button pushed function: ButtonWayPointRouting
        function ButtonWayPointRoutingPushed(app, event)
            % 自动寻径需要完成的工作：
            % 1、根据点击坐标区产生途经点坐标（响应UIFigureWindowButtonDown），画线；
            % 2、途经点坐标发送给串口；
            % 3、根据当前位置，更新图标位置并显示。
            app.bCaptureCurrentPoint = false;
            if(~app.bAutoWayPoint && length(app.xRouteForward)>1)
                app.hLineForward = line(app.UIAxes,app.xRouteForward,app.yRouteForward,...
                    'LineWidth',1,'Color','red','LineStyle','--');
            end
            if(~isempty(app.hPoint))
                delete(app.hPoint);
            end
            app.xRouteBehind =[];
            app.yRouteBehind =[];

            if(size(app.xRouteForward,1)>1)
                app.xRouteForward = (app.xRouteForward)';
                app.yRouteForward = (app.yRouteForward)';
            end
            [rows, cols] = size(app.xRouteForward);
            
            % 演示行进路线
            app.COM2DataReadable = false;
            app.BehindLineColor = 2;
            for k = 1:rows
                RouteDataX = app.xRouteForward(k,:);
                RouteDataY = app.yRouteForward(k,:);
                % 3、根据即将行进的路径，更新图标位置并显示。
%                 len = length(RouteDataX);
                angle = zeros(1,cols-1);
                num = 5;
                routeX = zeros(cols-1,num);
                routeY = zeros(cols-1,num);
                for i = 2:cols
                    routeX(i-1,:) = linspace(RouteDataX(i-1),RouteDataX(i),num);
                    routeY(i-1,:) = linspace(RouteDataY(i-1),RouteDataY(i),num);
                    b = RouteDataY(i)-RouteDataY(i-1);
                    a = RouteDataX(i)-RouteDataX(i-1);
                    vTan = b/a;
                    r = 180/pi;
                    angle(i-1) = atan(vTan)*r;
                    if(a<0 && b>0)
                        angle(i-1) = 180 + atan(vTan)*r;
                    elseif(a<0 && b<0)
                        angle(i-1) = 180 + atan(vTan)*r;
                    elseif(a>0 && b<0)
                        angle(i-1) = 360 + atan(vTan)*r;
                    end
                end
                app.bRectangle = false;   % 自动行进时，只出现三角形
                angle = angle - 90;    % 初始方向为90度，
%                 演示用的，实际操作中就注释掉这一段
                for kx = 1:cols-1
                    for ky = 1:num
                        app.EditRobotPosX.Value = routeX(kx,ky);
                        app.EditRobotPosY.Value = routeY(kx,ky);
                        app.RobotYaw = 270 - angle(kx);   % 角度到航向角的换算
                        app.EditFieldOriention.Value = mod(app.RobotYaw,360);
                        app.BehindLineColor = 1;   % 画线
                        currentRobotPos(app);
                        pause(0.1);
                    end
                end
            end
            app.BehindLineColor = 1;


            % 向串口发送指令，机器人将根据指令按规划路线行进。
            app.COM2DataReadable = true;
            btn = questdlg('机器人是否按路线行进？', '真机移动', '是','否','是');
            if(isequal(btn,'否'))
                return;
            end
            
            for k = 1:rows
                RouteDataX = app.xRouteForward(k,:);
                RouteDataY = app.yRouteForward(k,:);
%                 if(mod(k+1,2))    % 做到首尾相接    2020.11.26
%                     RouteDataX = fliplr(RouteDataX);
%                     RouteDataY = fliplr(RouteDataY);
%                 end
%                 len = length(RouteDataX);
                for i = 1:cols
                    UTME = RouteDataX(i);
                    UTMN = RouteDataY(i);
                    [Lat,Lon] = UTM2LatLon(UTME,UTMN,app.InitRobotLat,app.InitRobotLon);
                    OrderType = '02';
                    [LatHex] = LatLon2Bytes(Lat);
                    [LonHex] = LatLon2Bytes(Lon);
                    AngleV = sprintf('%02X',app.EditScanVelocity.Value);
                    Step = sprintf('%02X',app.EditDeltaY.Value*10);
                    Radius = app.EditScanRadius.Value*100;
                    Radius = CHValueTransform(Radius,1);
                    SequenceNumber = sprintf('%02X',(k-1)*cols+i);
                    TotalNumber = sprintf('%02X',rows*cols);
                    [Order] = controlOrder(LonHex,LatHex,AngleV,Step,OrderType,Radius,SequenceNumber,TotalNumber);
                    fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
                    pause(0.1)
                    
                    txt = ['平面坐标(',num2str(UTME),',',num2str(UTMN),')', newline,...
                        'GPS坐标(',num2str(Lon,10),',',num2str(Lat,10),')'];
                    app.hAxesText = [app.hAxesText text(app.UIAxes,UTME,UTMN,txt)];
                end

            end
            app.bRectangle = false;    % 自动行进结束后，又出现前方矩形

        end

        % Button pushed function: ButtonClearRoute
        function ButtonClearRoutePushed(app, event)
            if(~isempty(app.hTriangle))
                delete(app.hTriangle);
            end
            if(~isempty(app.hRectangle))
                delete(app.hRectangle);
            end
            if(~isempty(app.hLineForward))
                delete(app.hLineForward);
            end
            if(~isempty(app.hLineBehind))
                delete(app.hLineBehind);
            end
            if(~isempty(app.hAxesText))
                delete(app.hAxesText);
            end            
            app.xRouteBehind =[];
            app.yRouteBehind =[];
            app.RobotYaw = 0;

        end

        % Menu selected function: MenuSignalLib
        function MenuSignalLibSelected(app, event)
            [filename,pathname] = uigetfile( {'*.mat';'*.*'}, '载入神经网络权值');
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            app.FileCoef = [pathname,filename];
            msgbox('载入识别样本库成功！');
        end

        % Menu selected function: MenuImageLib
        function MenuImageLibSelected(app, event)
            [filename,pathname] = uigetfile( {'*.mat';'*.*'}, '载入样本库');
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            app.ImageLib = [pathname,filename];
            msgbox('载入图片样本库成功！');
        end

        % Button pushed function: ButtonFileTargetRecognize
        function ButtonFileTargetRecognizePushed(app, event)
            value = '文件'; %app.DropDownTargetType.Value;
            app.bCheckEnable = false;
            if(contains(value,'文件'))  
                ButtonSingleTargetRecognizePushed(app, event);
            else
                if(isempty(app.FileCoef))
                    msgbox('请载入权值文件');
                    ButtonCoefLoadPushed(app, event);
                end
                % % 选择数据文件路径
                FolderName = uigetdir('..\OriginDat','请选择数据文件路径。');
                if(isequal(FolderName,0))
                    errordlg('载入文件失败');
                    return;
                end
                % FolderName = 'E:\matlab\OriginDat\20190305';    % 调试时用这个默认路径。
                A = dir(fullfile(FolderName,'*.dat'));
                % %列表选择对话框
                AllFileNames = cell(size(A));
                for i=1:size(A,1)
                    AllFileNames{i,1} = A(i,1).name;
                end
                [Sel,Ok]=listdlg('liststring',AllFileNames,...
                    'listsize',[360 500],'OkString','确定','CancelString','取消',...
                    'promptstring','请选择数据文件','name','请选择数据文件（多选）',...
                    'selectionmode','multiple');
                if(Ok == 0)
                    msgbox('您取消了选择文件。');
                    return;
                end
                FileName = A(Sel,1);
                FullName = A(Sel,1);
                FileNumSel = size(FileName,1);
                for k=1:FileNumSel
                    FullName(k,1).name = strcat(FolderName,'\', FileName(k,1).name);
                end
                
                set(app.UITable, 'Data', []);
    
                TargetMaxWidth = app.EditMinSpan.Value/100;
                v = app.EditScanVelocity_2.Value;
                fs = app.EditFs.Value;
                ScanLineRange = app.EditScanRadius.Value*app.EditScanAngle.Value*pi/180;
                MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);    % 每次扫描目标个数上限。
                SignalWidth = 2*TargetMaxWidth/v*fs;
                [TableData,~] = targetRecognize(app,FullName,FileName,...
                    SignalWidth,MaxTargetNum);   % 进入识别函数。
                TableColumnName = {'序号','文件名','选中？','识别结果'};
                setTableView(app,TableColumnName,TableData);
            end

        end

        % Menu selected function: MenuVersionApplier, 
        % MenuVersionDeveloper
        function MenuVersionDeveloperSelected(app, event)
            if(strcmp(app.MenuVersionDeveloper.Checked,'on'))
                app.MenuVersionDeveloper.Checked = 'off';
                app.MenuVersionApplier.Checked = 'on';
                app.UIFigure.Name = strcat('目标定位与识别系统实操应用版 -- ',...
                    app.SwitchEquipment.Value);
            else
                app.MenuVersionDeveloper.Checked = 'on';
                app.MenuVersionApplier.Checked = 'off';
                app.UIFigure.Name = strcat('目标定位与识别系统开发调试版 -- ',...
                    app.SwitchEquipment.Value);
            end

        end

        % Button pushed function: ButtonAutoWayPoint
        function ButtonAutoWayPointPushed(app, event)
            app.bCaptureCurrentPoint = false;
            app.bAutoWayPoint = true;
            app.bRectangle = false;
            btn = questdlg('请选择路径形式', '提示', '弓字形','漩涡形','弓字形');
            switch btn
                case '弓字形'
                    [RouteX, RouteY] = autoGenRakeRoute(app.xPolygon,app.yPolygon,app.ScanWidth.Value);
                    app.xRouteForward = RouteX';
                    app.yRouteForward = RouteY';
%                     for i = 1:length(Rect)
%                         h = Rect(i).Ytop - Rect(i).Ybottom;
%                         rectangle(app.UIAxes,'Position',[Rect(i).Xleft,Rect(i).Ybottom,1,h],'EdgeColor','b');
%                     end
                case '漩涡形'
                    [app.xRouteForward,app.yRouteForward] = autoGenWhirlpoolRoute(app.xPolygon,app.yPolygon,app.ScanWidth.Value);
            end
            hold(app.UIAxes,'on');
            if(length(app.xRouteForward)>1)
                Xplot = app.xRouteForward;
                Yplot = app.yRouteForward;
                [m,n] = size(Xplot);
                Xplot2 = zeros(1,m*n);
                Yplot2 = Xplot2;
                for i = 1:m
                    Xplot2(i:m:end) = Xplot(i,:);
                    Yplot2(i:m:end) = Yplot(i,:);
                end
                app.hLineForward = line(app.UIAxes,Xplot2,Yplot2,'LineWidth',1,'Color','red','LineStyle','--');
            end
            hold(app.UIAxes,'off');

            app.bRectangle = false;

        end

        % Button pushed function: ButtonCom1Control
        function ButtonCom1ControlPushed(app, event)
            if(contains(app.ButtonCom1Control.Text,'打开'))
                openDataCom(app);   
                app.DispData = [];
                app.ButtonCom1Control.Text = '关闭串口1';
            else
                closeDataCom(app);    
                app.ButtonCom1Control.Text = '打开串口1';
            end
            setLampStatus(app);
            
        end

        % Button pushed function: ButtonCom2Control
        function ButtonCom2ControlPushed(app, event)
            if(contains(app.ButtonCom2Control.Text,'打开'))
                openControlCom(app);    
                app.ButtonCom2Control.Text = '关闭串口2';
            else
                closeControlCom(app);    
                app.ButtonCom2Control.Text = '打开串口2';
            end
            setLampStatus2(app);

        end

        % Button pushed function: ButtonInitialRobost
        function ButtonInitialRobostPushed(app, event)
            h = msgbox({'初始化完成后，此对话框自动关闭，请勿操作！';...
                '初始化将自动完成以下工作：';...
                '1、打开串口2，接收机器人当前经纬度，定义为原点。';...
                '2、通过串口2发送角速度、步进参数指令。';...
                },'初始化将自动完成以下工作');
            %% 初始化机器人位置
%             delete(instrfindall);
            app.COM1DataReadable = true;
            openControlCom(app);
            setLampStatus2(app);
%             if(strcmp(app.SCom2.Status,'open'))
%                 app.ButtonCom2Control.Text = '关闭串口2';
%             end
            while(isempty(app.RobotLat))
                pause(1);     % 这个等待用于接收串口数据。
            end
            Lat = app.RobotLat;
            Lon = app.RobotLon;
            app.InitRobotLat = Lat;
            app.InitRobotLon = Lon;
            app.RobotLat0 = Lat(end);
            app.RobotLon0 = Lon(end);
            app.EditFieldRobotLat.Value = app.RobotLat0;
            app.EditFieldRobotLon.Value = app.RobotLon0;
            axis(app.UIAxes,[-3,3,-3,3]);
            app.xRouteBehind = 0;
            app.yRouteBehind = 0;
            app.COM1DataReadable = false;
            %% 初始化机器人参数
            OrderType = '05';  
            [Lat] = LatLon2Bytes(app.RobotLat0);
            [Lon] = LatLon2Bytes(app.RobotLon0);
            AngleV = sprintf('%02X',app.EditScanVelocity.Value);
            Step = sprintf('%02X',app.EditDeltaY.Value*10);
            Radius = app.EditScanRadius.Value*100;
            Radius = CHValueTransform(Radius,1);
            SequenceNumber = '00';
            TotalNumber = '00';
            [Order] = controlOrder(Lon,Lat,AngleV,Step,OrderType,Radius,SequenceNumber,TotalNumber);
            fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
            % 要关一次，再打开，才能实时更新。什么鬼？
            app.BehindLineColor = 3;   % 不画线
            currentRobotPos(app);   % 在坐标中显示。角度尚未考虑
            closeControlCom(app);
            pause(0.5)
            openControlCom(app);
%             setLampStatus2(app);
%             if(strcmp(app.SCom2.Status,'close'))
%                 app.ButtonCom2Control.Text = '打开串口2';
%             end
            
            if(~isempty(h))
                delete(h);
            end
        end

        % Button pushed function: ButtonMarkPosition
        function ButtonMarkPositionPushed(app, event)
            app.bCaptureCurrentPoint = false;
            app.COM2DataReadable = true;      % 设置是否响应串口2
            len = length(app.XPos);
            for i = 1:len
                UTME = app.XPos(i);
                UTMN = app.YPos(i);
                [Lat,Lon] = UTM2LatLon(UTME,UTMN,app.InitRobotLat,app.InitRobotLon);
                OrderType = '03';
                [Lat] = LatLon2Bytes(Lat);
                [Lon] = LatLon2Bytes(Lon);
                AngleV = sprintf('%02X',app.EditScanVelocity.Value);
                Step = sprintf('%02X',app.EditDeltaY.Value*10);
                Radius = app.EditScanRadius.Value*100;
                Radius = CHValueTransform(Radius,1);
                SequenceNumber = sprintf('%02X',i);
                TotalNumber = sprintf('%02X',len);
                [Order] = controlOrder(Lon,Lat,AngleV,Step,OrderType,Radius,SequenceNumber,TotalNumber);
                fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
                pause(0.1)
            end

        end

        % Menu selected function: MenuImageTrain
        function MenuImageTrainSelected(app, event)
            ButtonImageTrainPushed(app,event);
        end

        % Menu selected function: MenuCharisticValue
        function MenuCharisticValueSelected(app, event)
            TargetMaxWidth = app.EditMinSpan.Value/100;
            v = app.EditScanVelocity_2.Value;
            fs = app.EditFs.Value;
            ScanLineRange = app.EditScanRadius.Value*app.EditScanAngle.Value*pi/180;
            MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);    % 每次扫描目标个数上限。
            SignalWidth = 2*TargetMaxWidth/v*fs;
%             MaxTargetNum = floor(ScanLineRange/TargetMaxWidth);
            [app.FileSavePath,app.MeanValue] = characteristicValue(SignalWidth,MaxTargetNum);

        end

        % Menu selected function: MenuTrainSettings
        function MenuTrainSettingsSelected(app, event)
            TrainSettings(app);
        end

        % Menu selected function: MenuSaveCoef
        function MenuSaveCoefSelected(app, event)
            [filename,pathname] = uiputfile( {'*.mat';'*.*'}, '保存神经网络权值');
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            Coef = app.Coef;
            save([pathname,filename],'Coef');
            msgbox('神经网络权值保存成功！');
            
        end

        % Menu selected function: MenuManual
        function MenuManualSelected(app, event)
            docFile = strcat(pwd,'\用户手册.doc');
            winopen(docFile);
        end

        % Menu selected function: MenuVersionInfo
        function MenuVersionInfoSelected(app, event)
            msgbox({'             目标定位与识别系统1.0';...
                    '                                 ';...
                    '     Copyright © 2020 - 2025 Wayful. ';...
                    '             All Rights Reserved.';...
                    '             卫富科技   版权所有';...
                    },'版本信息');
        end

        % Button pushed function: ButtonEmergency
        function ButtonEmergencyPushed(app, event)
            %% 退出当前模式指令
            OrderType = '00';  
            [Lat] = LatLon2Bytes(app.RobotLat0);
            [Lon] = LatLon2Bytes(app.RobotLon0);
            AngleV = sprintf('%02X',app.EditScanVelocity.Value);
            Step = sprintf('%02X',app.EditDeltaY.Value*10);
            Radius = app.EditScanRadius.Value*100;
            Radius = CHValueTransform(Radius,1);
            SequenceNumber = '00';
            TotalNumber = '00';
            [Order] = controlOrder(Lon,Lat,AngleV,Step,OrderType,Radius,SequenceNumber,TotalNumber);
            fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。            
        end

        % Button pushed function: ButtonResetCOM
        function ButtonResetCOMPushed(app, event)
            delete(instrfindall);  
            app.LampStatus.Color = [1,0,0];
            app.ButtonCom1Control.Text = '打开串口1';
            app.LampStatus_2.Color = [1,0,0];
            app.ButtonCom2Control.Text = '打开串口2';

        end

        % Button pushed function: ButtonCoordinateBack
        function ButtonCoordinateBackPushed(app, event)
            check = cell2mat(app.UITable.Data{:,3});
%             app.COM2DataReadable = true;      % 设置是否响应串口2

            [I,~] = find(check);
            x = app.XPos(I);
            y = app.YPos(I);
            NUM = length(x);
            XCode = ['00'; '00'];
            YCode = ['00'; '00'];
            
            % 回传坐标
            for i=1:NUM
                UTME = x(i);
                UTMN = y(i);
                [Lat,Lon] = UTM2LatLon(UTME,UTMN,app.InitRobotLat,app.InitRobotLon);
                OrderType = '04';
                [LatHex] = LatLon2Bytes(Lat);
                [LonHex] = LatLon2Bytes(Lon);
                AngleV = sprintf('%02X',app.EditScanVelocity.Value);
                Step = sprintf('%02X',app.EditDeltaY.Value*10);
                Radius = app.EditScanRadius.Value*100;
                Radius = CHValueTransform(Radius,1);
                SequenceNumber = sprintf('%02X',i);
                TotalNumber = sprintf('%02X',NUM);
                Xcoordinate = dec2hex(round(UTME*100+400),4);  XCode(1,:) = Xcoordinate(3:4); XCode(2,:) = Xcoordinate(1:2);
                Ycoordinate = dec2hex(round(UTMN*100+400),4);  YCode(1,:) = Ycoordinate(3:4); YCode(2,:) = Ycoordinate(1:2);
                [Order] = passPosition(LonHex,LatHex,AngleV,Step,OrderType,Radius,SequenceNumber,TotalNumber,XCode,YCode);
                fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
                pause(0.1)
            end


        end

        % Button pushed function: ButtonRecoginizeSelected
        function ButtonRecoginizeSelectedPushed(app, event)
            app.COM2DataReadable = true;      % 设置是否响应串口2

            x = [0,0.2];
            y = [1.4,1.3];
            NUM = length(x);
            XCode = ['00'; '00'];
            YCode = ['00'; '00'];
            
            % 回传坐标
            for i=1:NUM
                UTME = x(i);
                UTMN = y(i);
                [Lat,Lon] = UTM2LatLon(UTME,UTMN,app.InitRobotLat,app.InitRobotLon);
                OrderType = '04';
                [LatHex] = LatLon2Bytes(Lat);
                [LonHex] = LatLon2Bytes(Lon);
                AngleV = sprintf('%02X',app.EditScanVelocity.Value);
                Step = sprintf('%02X',app.EditDeltaY.Value*10);
                Radius = app.EditScanRadius.Value*100;
                Radius = CHValueTransform(Radius,1);
                SequenceNumber = sprintf('%02X',i);
                TotalNumber = sprintf('%02X',NUM);
                Xcoordinate = dec2hex(round(UTME*100+400),4);  XCode(1,:) = Xcoordinate(3:4); XCode(2,:) = Xcoordinate(1:2);
                Ycoordinate = dec2hex(round(UTMN*100+400),4);  YCode(1,:) = Ycoordinate(3:4); YCode(2,:) = Ycoordinate(1:2);
                [Order] = passPosition(LonHex,LatHex,AngleV,Step,OrderType,Radius,SequenceNumber,TotalNumber,XCode,YCode);
                fwrite(app.SCom2, Order, 'uint8', 'sync');  % 写入二进制用fwrite。
                pause(0.1)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '目标定位与识别系统';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.MenuFile = uimenu(app.UIFigure);
            app.MenuFile.Text = '文件';
            app.MenuVersionSelect = uimenu(app.MenuFile);
            app.MenuVersionSelect.Text = '版本选择';
            app.MenuVersionDeveloper = uimenu(app.MenuVersionSelect);
            app.MenuVersionDeveloper.MenuSelectedFcn = createCallbackFcn(app, @MenuVersionDeveloperSelected, true);
            app.MenuVersionDeveloper.Text = '开发调试版';
            app.MenuVersionApplier = uimenu(app.MenuVersionSelect);
            app.MenuVersionApplier.MenuSelectedFcn = createCallbackFcn(app, @MenuVersionDeveloperSelected, true);
            app.MenuVersionApplier.Checked = 'on';
            app.MenuVersionApplier.Text = '实操应用版';
            app.MenuExit = uimenu(app.MenuFile);
            app.MenuExit.MenuSelectedFcn = createCallbackFcn(app, @MenuExitSelected, true);
            app.MenuExit.Text = '退出';
            app.MenuSettings = uimenu(app.UIFigure);
            app.MenuSettings.Text = '设置';
            app.MenuComSettings = uimenu(app.MenuSettings);
            app.MenuComSettings.MenuSelectedFcn = createCallbackFcn(app, @MenuComSettingsSelected, true);
            app.MenuComSettings.Text = '串口设置';
            app.MenuInitialRobost = uimenu(app.MenuSettings);
            app.MenuInitialRobost.Text = '机器初始化';
            app.MenuLPFSettings = uimenu(app.MenuSettings);
            app.MenuLPFSettings.MenuSelectedFcn = createCallbackFcn(app, @MenuLPFSettingsSelected, true);
            app.MenuLPFSettings.Text = '滤波器设置';
            app.MenuImageLib = uimenu(app.MenuSettings);
            app.MenuImageLib.MenuSelectedFcn = createCallbackFcn(app, @MenuImageLibSelected, true);
            app.MenuImageLib.Text = '地表目标库';
            app.MenuSignalLib = uimenu(app.MenuSettings);
            app.MenuSignalLib.MenuSelectedFcn = createCallbackFcn(app, @MenuSignalLibSelected, true);
            app.MenuSignalLib.Text = '地下目标库';
            app.MenuTrainBP = uimenu(app.UIFigure);
            app.MenuTrainBP.Text = '样本学习';
            app.MenuImageTrain = uimenu(app.MenuTrainBP);
            app.MenuImageTrain.MenuSelectedFcn = createCallbackFcn(app, @MenuImageTrainSelected, true);
            app.MenuImageTrain.Enable = 'off';
            app.MenuImageTrain.Text = '地表样本学习';
            app.MenuGroundTrain = uimenu(app.MenuTrainBP);
            app.MenuGroundTrain.Enable = 'off';
            app.MenuGroundTrain.Separator = 'on';
            app.MenuGroundTrain.Text = '地下样本学习';
            app.MenuCharisticValue = uimenu(app.MenuGroundTrain);
            app.MenuCharisticValue.MenuSelectedFcn = createCallbackFcn(app, @MenuCharisticValueSelected, true);
            app.MenuCharisticValue.Text = '提取特征值';
            app.MenuTrainSettings = uimenu(app.MenuGroundTrain);
            app.MenuTrainSettings.MenuSelectedFcn = createCallbackFcn(app, @MenuTrainSettingsSelected, true);
            app.MenuTrainSettings.Text = '训练参数设置';
            app.MenuTrain = uimenu(app.MenuGroundTrain);
            app.MenuTrain.Text = '开始学习';
            app.MenuSaveCoef = uimenu(app.MenuGroundTrain);
            app.MenuSaveCoef.MenuSelectedFcn = createCallbackFcn(app, @MenuSaveCoefSelected, true);
            app.MenuSaveCoef.Text = '保存训练结果';
            app.MenuHelp = uimenu(app.UIFigure);
            app.MenuHelp.Text = '帮助';
            app.MenuManual = uimenu(app.MenuHelp);
            app.MenuManual.MenuSelectedFcn = createCallbackFcn(app, @MenuManualSelected, true);
            app.MenuManual.Text = '操作手册';
            app.MenuVersionInfo = uimenu(app.MenuHelp);
            app.MenuVersionInfo.MenuSelectedFcn = createCallbackFcn(app, @MenuVersionInfoSelected, true);
            app.MenuVersionInfo.Text = '版本信息';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.ZLim = [-2 2];
            app.TabGroupDomainRoute = uitabgroup(app.UIFigure);
            app.TabDomainRoute = uitab(app.TabGroupDomainRoute);
            app.TabDomainRoute.Title = '雷区与路线';
            app.Label_64 = uilabel(app.TabDomainRoute);
            app.Label_64.Text = '顶点坐标';
            app.EditFieldX = uieditfield(app.TabDomainRoute, 'numeric');
            app.EditFieldX.Limits = [-180 180];
            app.EditFieldX.ValueDisplayFormat = '%.2f';
            app.EditFieldX.Value = 123.4415;
            app.EditFieldY = uieditfield(app.TabDomainRoute, 'numeric');
            app.EditFieldY.Limits = [-180 180];
            app.EditFieldY.ValueDisplayFormat = '%.2f';
            app.EditFieldY.Value = 41.7608;
            app.PanelDomain = uipanel(app.TabDomainRoute);
            app.PanelDomain.Title = '雷区绘制';
            app.ButtonDomain = uibutton(app.PanelDomain, 'push');
            app.ButtonDomain.ButtonPushedFcn = createCallbackFcn(app, @ButtonDomainPushed, true);
            app.ButtonDomain.Tooltip = {''};
            app.ButtonDomain.Text = '绘制雷区';
            app.ButtonAddVertex = uibutton(app.PanelDomain, 'push');
            app.ButtonAddVertex.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddVertexPushed, true);
            app.ButtonAddVertex.Tooltip = {''};
            app.ButtonAddVertex.Text = '坐标增加顶点';
            app.ButtonDomainClear = uibutton(app.PanelDomain, 'push');
            app.ButtonDomainClear.ButtonPushedFcn = createCallbackFcn(app, @ButtonDomainClearPushed, true);
            app.ButtonDomainClear.Tooltip = {''};
            app.ButtonDomainClear.Text = '清除坐标';
            app.ButtonAddVertex_2 = uibutton(app.PanelDomain, 'push');
            app.ButtonAddVertex_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddVertex_2Pushed, true);
            app.ButtonAddVertex_2.Tooltip = {'在坐标区域内，依次点击产生顶点。'};
            app.ButtonAddVertex_2.Text = '鼠标增加顶点';
            app.Panel = uipanel(app.TabDomainRoute);
            app.Panel.Title = '路线规划';
            app.ButtonClearRoute = uibutton(app.Panel, 'push');
            app.ButtonClearRoute.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearRoutePushed, true);
            app.ButtonClearRoute.Text = '清除路径';
            app.ButtonWayPointRouting = uibutton(app.Panel, 'push');
            app.ButtonWayPointRouting.ButtonPushedFcn = createCallbackFcn(app, @ButtonWayPointRoutingPushed, true);
            app.ButtonWayPointRouting.Text = '演示行进路线';
            app.ButtonManualWayPoint = uibutton(app.Panel, 'push');
            app.ButtonManualWayPoint.ButtonPushedFcn = createCallbackFcn(app, @ButtonManualWayPointPushed, true);
            app.ButtonManualWayPoint.Enable = 'off';
            app.ButtonManualWayPoint.Tooltip = {'点击坐标区增加途经点'};
            app.ButtonManualWayPoint.Text = '人工标定路径';
            app.ButtonAutoWayPoint = uibutton(app.Panel, 'push');
            app.ButtonAutoWayPoint.ButtonPushedFcn = createCallbackFcn(app, @ButtonAutoWayPointPushed, true);
            app.ButtonAutoWayPoint.Text = '自主规划路径';
            app.SwitchMediumType = uiswitch(app.TabDomainRoute, 'slider');
            app.SwitchMediumType.Items = {'粘土', '砂土'};
            app.SwitchMediumType.Value = '粘土';
            app.Label_63 = uilabel(app.TabDomainRoute);
            app.Label_63.Text = '探扫宽度(米)';
            app.ScanWidth = uieditfield(app.TabDomainRoute, 'numeric');
            app.ScanWidth.Limits = [0 5];
            app.ScanWidth.ValueDisplayFormat = '%.1f';
            app.ScanWidth.Value = 2;
            app.SwitchCoordinateType = uiswitch(app.TabDomainRoute, 'slider');
            app.SwitchCoordinateType.Items = {'经纬度', '直角'};
            app.SwitchCoordinateType.Value = '经纬度';
            app.TabScan = uitab(app.TabGroupDomainRoute);
            app.TabScan.Title = '探测操作';
            app.SwitchOrientation = uiswitch(app.TabScan, 'slider');
            app.SwitchOrientation.Items = {'顺时针', '逆时针'};
            app.SwitchOrientation.Value = '顺时针';
            app.Label_58 = uilabel(app.TabScan);
            app.Label_58.Text = '扫描半径(厘米)';
            app.EditScanRadius = uieditfield(app.TabScan, 'numeric');
            app.EditScanRadius.Limits = [0 400];
            app.EditScanRadius.ValueDisplayFormat = '%.0f';
            app.EditScanRadius.ValueChangedFcn = createCallbackFcn(app, @EditScanRadiusValueChanged, true);
            app.EditScanRadius.Editable = 'off';
            app.EditScanRadius.Value = 150;
            app.ButtonGroup_2 = uibuttongroup(app.TabScan);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @Switch2D3DChanged, true);
            app.ButtonGroup_2.Title = '显示选择';
            app.CH1Button = uiradiobutton(app.ButtonGroup_2);
            app.CH1Button.Text = 'CH1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.ButtonGroup_2);
            app.CH2Button.Text = 'CH2';
            app.CH3Button = uiradiobutton(app.ButtonGroup_2);
            app.CH3Button.Text = 'CH3';
            app.Switch2D3D = uiswitch(app.ButtonGroup_2, 'slider');
            app.Switch2D3D.Items = {'等高线显示', '三维显示'};
            app.Switch2D3D.ValueChangedFcn = createCallbackFcn(app, @Switch2D3DChanged, true);
            app.Switch2D3D.Value = '等高线显示';
            app.EditFieldSensorPosY = uieditfield(app.TabScan, 'numeric');
            app.EditFieldSensorPosY.ValueDisplayFormat = '%.2f';
            app.Label_55 = uilabel(app.TabScan);
            app.Label_55.Text = '探测器坐标';
            app.EditFieldSensorPosX = uieditfield(app.TabScan, 'numeric');
            app.EditFieldSensorPosX.ValueDisplayFormat = '%.2f';
            app.ButtonStartScan = uibutton(app.TabScan, 'push');
            app.ButtonStartScan.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartScanPushed, true);
            app.ButtonStartScan.Enable = 'off';
            app.ButtonStartScan.Text = '开始探扫';
            app.ButtonPrepareScan = uibutton(app.TabScan, 'push');
            app.ButtonPrepareScan.ButtonPushedFcn = createCallbackFcn(app, @ButtonPrepareScanPushed, true);
            app.ButtonPrepareScan.Tooltip = {''};
            app.ButtonPrepareScan.Text = '探扫准备';
            app.ButtonStopScan = uibutton(app.TabScan, 'push');
            app.ButtonStopScan.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopScanPushed, true);
            app.ButtonStopScan.Enable = 'off';
            app.ButtonStopScan.Text = '停止探扫';
            app.ButtonContour = uibutton(app.TabScan, 'push');
            app.ButtonContour.ButtonPushedFcn = createCallbackFcn(app, @ButtonContourPushed, true);
            app.ButtonContour.Tooltip = {''};
            app.ButtonContour.Text = '区域成像';
            app.SwitchScanMethod = uiswitch(app.TabScan, 'slider');
            app.SwitchScanMethod.Items = {'人工逐次探扫', '区域自动探扫'};
            app.SwitchScanMethod.ValueChangedFcn = createCallbackFcn(app, @SwitchScanMethodValueChanged, true);
            app.SwitchScanMethod.Value = '区域自动探扫';
            app.Label_49 = uilabel(app.TabScan);
            app.Label_49.Text = '当前角度(度)';
            app.EditCurrentAngle = uieditfield(app.TabScan, 'numeric');
            app.EditCurrentAngle.Limits = [-360 360];
            app.EditCurrentAngle.ValueDisplayFormat = '%.0f';
            app.EditCurrentAngle.Editable = 'off';
            app.Label_75 = uilabel(app.TabScan);
            app.Label_75.Text = '探扫状态';
            app.CurScanStatus = uitextarea(app.TabScan);
            app.CurScanStatus.Editable = 'off';
            app.CurScanStatus.Value = {'无操作'};
            app.Label_76 = uilabel(app.TabScan);
            app.Label_76.Text = '扫描高度(厘米)';
            app.EditScanHeight = uieditfield(app.TabScan, 'numeric');
            app.EditScanHeight.ValueDisplayFormat = '%.1f';
            app.EditScanHeight.Editable = 'off';
            app.EditScanHeight.Value = 10;
            app.Label_77 = uilabel(app.TabScan);
            app.Label_77.Text = '先前';
            app.EditFieldPreStatus = uieditfield(app.TabScan, 'numeric');
            app.EditFieldPreStatus.Limits = [0 4];
            app.EditFieldPreStatus.ValueDisplayFormat = '%.0f';
            app.Label_78 = uilabel(app.TabScan);
            app.Label_78.Text = '当前';
            app.EditFieldCurStatus = uieditfield(app.TabScan, 'numeric');
            app.EditFieldCurStatus.Limits = [0 4];
            app.EditFieldCurStatus.ValueDisplayFormat = '%.0f';
            app.TabPositionRecognize = uitab(app.TabGroupDomainRoute);
            app.TabPositionRecognize.Title = '目标定位与识别';
            app.PanelPosition = uipanel(app.TabPositionRecognize);
            app.PanelPosition.Title = '定位与识别';
            app.ButtonPosition = uibutton(app.PanelPosition, 'push');
            app.ButtonPosition.ButtonPushedFcn = createCallbackFcn(app, @ButtonPositionPushed, true);
            app.ButtonPosition.Tooltip = {''};
            app.ButtonPosition.Text = '定位目标';
            app.ButtonMarkPosition = uibutton(app.PanelPosition, 'push');
            app.ButtonMarkPosition.ButtonPushedFcn = createCallbackFcn(app, @ButtonMarkPositionPushed, true);
            app.ButtonMarkPosition.Text = '标识目标';
            app.ButtonRecognize = uibutton(app.PanelPosition, 'push');
            app.ButtonRecognize.Text = '识别目标';
            app.PanelRecognize = uipanel(app.TabPositionRecognize);
            app.PanelRecognize.Title = '历史数据识别';
            app.ButtonFileTargetRecognize = uibutton(app.PanelRecognize, 'push');
            app.ButtonFileTargetRecognize.ButtonPushedFcn = createCallbackFcn(app, @ButtonFileTargetRecognizePushed, true);
            app.ButtonFileTargetRecognize.Text = '单个文件识别';
            app.ButtonMultiTargetRecognize = uibutton(app.PanelRecognize, 'push');
            app.ButtonMultiTargetRecognize.ButtonPushedFcn = createCallbackFcn(app, @ButtonMultiTargetRecognizePushed, true);
            app.ButtonMultiTargetRecognize.Text = '文件批量识别';
            app.EditFieldTargetType = uieditfield(app.PanelRecognize, 'text');
            app.EditFieldTargetType.Editable = 'off';
            app.EditFieldTargetType.Value = '识别结果';
            app.TabImageRecognition = uitab(app.TabGroupDomainRoute);
            app.TabImageRecognition.Title = '地面目标识别';
            app.ButtonImageLoad = uibutton(app.TabImageRecognition, 'push');
            app.ButtonImageLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonImageLoadPushed, true);
            app.ButtonImageLoad.Text = '载入目标图片';
            app.EditFieldImageLoad = uieditfield(app.TabImageRecognition, 'text');
            app.ButtonImageRecognize = uibutton(app.TabImageRecognition, 'push');
            app.ButtonImageRecognize.ButtonPushedFcn = createCallbackFcn(app, @ButtonImageRecognizePushed, true);
            app.ButtonImageRecognize.Enable = 'off';
            app.ButtonImageRecognize.Text = '开始识别';
            app.Label_30 = uilabel(app.TabImageRecognition);
            app.Label_30.Text = '本次识别结果';
            app.EditFieldImageType = uieditfield(app.TabImageRecognition, 'text');
            app.ButtonImageExport = uibutton(app.TabImageRecognition, 'push');
            app.ButtonImageExport.ButtonPushedFcn = createCallbackFcn(app, @ButtonImageExportPushed, true);
            app.ButtonImageExport.Enable = 'off';
            app.ButtonImageExport.Text = '导出图片';
            app.EditFieldIntroduce = uieditfield(app.TabImageRecognition, 'text');
            app.EditFieldIntroduce.Value = '百科';
            app.UIAxes2 = uiaxes(app.TabImageRecognition);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.TabDebug = uitab(app.TabGroupDomainRoute);
            app.TabDebug.Title = '开发者功能';
            app.cmLabel_2 = uilabel(app.TabDebug);
            app.cmLabel_2.Text = '累积增量（cm）';
            app.EditAccumulateDeltaY = uieditfield(app.TabDebug, 'numeric');
            app.EditAccumulateDeltaY.Editable = 'off';
            app.EditAccumulateDeltaY.Enable = 'off';
            app.cmLabel_3 = uilabel(app.TabDebug);
            app.cmLabel_3.Text = '目标最小间隔（cm）';
            app.EditMinSpan = uieditfield(app.TabDebug, 'numeric');
            app.EditMinSpan.Limits = [0 180];
            app.EditMinSpan.Value = 10;
            app.DataSourceButtonGroup = uibuttongroup(app.TabDebug);
            app.DataSourceButtonGroup.Title = '数据来源';
            app.Button = uiradiobutton(app.DataSourceButtonGroup);
            app.Button.Enable = 'off';
            app.Button.Text = '本机文件';
            app.Button2 = uiradiobutton(app.DataSourceButtonGroup);
            app.Button2.Text = '实时扫描';
            app.Button2.Value = true;
            app.Knob = uiknob(app.TabDebug, 'discrete');
            app.Knob.Items = {'低', '中', '高'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Tooltip = {'调整通道1的门限'};
            app.Knob.Value = '中';
            app.SwitchEquipment = uiswitch(app.TabDebug, 'slider');
            app.SwitchEquipment.Items = {'型号1', '型号2'};
            app.SwitchEquipment.ValueChangedFcn = createCallbackFcn(app, @SwitchEquipmentValueChanged, true);
            app.SwitchEquipment.Value = '型号1';
            app.Label_27 = uilabel(app.TabDebug);
            app.Label_27.Text = '步进';
            app.Label_32 = uilabel(app.TabDebug);
            app.Label_32.Text = '探测灵敏度';
            app.Label_46 = uilabel(app.TabDebug);
            app.Label_46.Text = '度';
            app.Label_48 = uilabel(app.TabDebug);
            app.Label_48.Text = '角度范围';
            app.EditScanAngle = uieditfield(app.TabDebug, 'numeric');
            app.EditScanAngle.Limits = [0 180];
            app.EditScanAngle.ValueDisplayFormat = '%.0f';
            app.EditScanAngle.Value = 90;
            app.Label_31 = uilabel(app.TabDebug);
            app.Label_31.Text = '当前时间';
            app.EditFieldCurrentTime = uieditfield(app.TabDebug, 'text');
            app.EditFieldCurrentTime.Editable = 'off';
            app.Label_53 = uilabel(app.TabDebug);
            app.Label_53.Text = '次/秒';
            app.Label_54 = uilabel(app.TabDebug);
            app.Label_54.Text = '采样速率';
            app.EditFs = uieditfield(app.TabDebug, 'numeric');
            app.EditFs.Limits = [0 200];
            app.EditFs.ValueDisplayFormat = '%.0f';
            app.EditFs.Value = 80;
            app.Label_56 = uilabel(app.TabDebug);
            app.Label_56.Enable = 'off';
            app.Label_56.Text = '人工选择方向';
            app.DropDownDirection = uidropdown(app.TabDebug);
            app.DropDownDirection.Items = {'Y轴正向', 'X轴负向', 'Y轴负向', 'X轴正向'};
            app.DropDownDirection.ValueChangedFcn = createCallbackFcn(app, @DropDownDirectionValueChanged, true);
            app.DropDownDirection.Enable = 'off';
            app.DropDownDirection.Value = 'Y轴正向';
            app.Label_60 = uilabel(app.TabDebug);
            app.Label_60.Text = '线速度(米/秒)';
            app.EditScanVelocity_2 = uieditfield(app.TabDebug, 'numeric');
            app.EditScanVelocity_2.Limits = [0 4];
            app.EditScanVelocity_2.ValueDisplayFormat = '%.2f';
            app.EditScanVelocity_2.Enable = 'off';
            app.EditScanVelocity_2.Value = 0.4;
            app.cmLabel_4 = uilabel(app.TabDebug);
            app.cmLabel_4.Text = '辙宽(cm)';
            app.RobotWidthEditField = uieditfield(app.TabDebug, 'numeric');
            app.RobotWidthEditField.Limits = [0 Inf];
            app.RobotWidthEditField.ValueDisplayFormat = '%.0f';
            app.RobotWidthEditField.Value = 60;
            app.Label_39 = uilabel(app.TabDebug);
            app.Label_39.Enable = 'off';
            app.Label_39.Text = '角速度(度/秒)';
            app.EditScanVelocity = uieditfield(app.TabDebug, 'numeric');
            app.EditScanVelocity.Limits = [0 90];
            app.EditScanVelocity.ValueDisplayFormat = '%.0f';
            app.EditScanVelocity.ValueChangedFcn = createCallbackFcn(app, @EditScanVelocityValueChanged, true);
            app.EditScanVelocity.Enable = 'off';
            app.EditScanVelocity.Value = 20;
            app.EditDeltaY = uieditfield(app.TabDebug, 'numeric');
            app.EditDeltaY.Limits = [-50 50];
            app.EditDeltaY.ValueDisplayFormat = '%0.0f';
            app.EditDeltaY.Enable = 'off';
            app.EditDeltaY.Value = 10;
            app.Panel_2 = uipanel(app.UIFigure);
            app.LampStatus = uilamp(app.Panel_2);
            app.LampStatus_2 = uilamp(app.Panel_2);
            app.ButtonCom1Control = uibutton(app.Panel_2, 'push');
            app.ButtonCom1Control.ButtonPushedFcn = createCallbackFcn(app, @ButtonCom1ControlPushed, true);
            app.ButtonCom1Control.Tooltip = {''};
            app.ButtonCom1Control.Text = '打开串口1';
            app.ButtonCom2Control = uibutton(app.Panel_2, 'push');
            app.ButtonCom2Control.ButtonPushedFcn = createCallbackFcn(app, @ButtonCom2ControlPushed, true);
            app.ButtonCom2Control.Tooltip = {''};
            app.ButtonCom2Control.Text = '打开串口2';
            app.ButtonInitialRobost = uibutton(app.Panel_2, 'push');
            app.ButtonInitialRobost.ButtonPushedFcn = createCallbackFcn(app, @ButtonInitialRobostPushed, true);
            app.ButtonInitialRobost.Text = '机器初始化';
            app.ButtonCurrentPos = uibutton(app.Panel_2, 'push');
            app.ButtonCurrentPos.ButtonPushedFcn = createCallbackFcn(app, @ButtonCurrentPosPushed, true);
            app.ButtonCurrentPos.Tooltip = {'显示车的当前位置'};
            app.ButtonCurrentPos.Text = '机器人位置';
            app.EditRobotPosY = uieditfield(app.Panel_2, 'numeric');
            app.EditRobotPosY.Limits = [-5000 5000];
            app.EditRobotPosY.ValueDisplayFormat = '%.2f';
            app.EditRobotPosY.Tooltip = {'在扫描区域的相对位置Y'};
            app.EditRobotPosX = uieditfield(app.Panel_2, 'numeric');
            app.EditRobotPosX.Limits = [-5000 5000];
            app.EditRobotPosX.ValueDisplayFormat = '%.2f';
            app.EditRobotPosX.Tooltip = {'在扫描区域的相对位置X'};
            app.Label_52 = uilabel(app.Panel_2);
            app.Label_52.Text = '航向角';
            app.EditFieldOriention = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldOriention.Value = 270;
            app.Label_50 = uilabel(app.Panel_2);
            app.Label_50.Text = '经度';
            app.EditFieldRobotLon = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldRobotLon.Limits = [-180 180];
            app.EditFieldRobotLon.ValueDisplayFormat = '%14.14g';
            app.Label_51 = uilabel(app.Panel_2);
            app.Label_51.Text = '纬度';
            app.EditFieldRobotLat = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldRobotLat.Limits = [-90 90];
            app.EditFieldRobotLat.ValueDisplayFormat = '%14.14g';
            app.ButtonEmergency = uibutton(app.Panel_2, 'push');
            app.ButtonEmergency.ButtonPushedFcn = createCallbackFcn(app, @ButtonEmergencyPushed, true);
            app.ButtonEmergency.Tooltip = {'退出当前模式'};
            app.ButtonEmergency.Text = '退出当前模式';
            app.ButtonResetCOM = uibutton(app.Panel_2, 'push');
            app.ButtonResetCOM.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetCOMPushed, true);
            app.ButtonResetCOM.Text = '重置串口';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '目标判读';
            app.ButtonTableExport = uibutton(app.Panel_3, 'push');
            app.ButtonTableExport.ButtonPushedFcn = createCallbackFcn(app, @ButtonTableExportPushed, true);
            app.ButtonTableExport.Text = '导出表格';
            app.ButtonTableDataDelete = uibutton(app.Panel_3, 'push');
            app.ButtonTableDataDelete.ButtonPushedFcn = createCallbackFcn(app, @ButtonTableDataDeletePushed, true);
            app.ButtonTableDataDelete.Text = '删除所选';
            app.ButtonRecoginizeSelected = uibutton(app.Panel_3, 'push');
            app.ButtonRecoginizeSelected.ButtonPushedFcn = createCallbackFcn(app, @ButtonRecoginizeSelectedPushed, true);
            app.ButtonRecoginizeSelected.Enable = 'off';
            app.ButtonRecoginizeSelected.Text = '调试按键';
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'序号'; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ButtonCoordinateBack = uibutton(app.Panel_3, 'push');
            app.ButtonCoordinateBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonCoordinateBackPushed, true);
            app.ButtonCoordinateBack.Text = '回传坐标';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end