classdef ScanSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ButtonOK            matlab.ui.control.Button
        ButtonCancel        matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        EditFieldLabel      matlab.ui.control.Label
        EditScanRadius      matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        EditScanVelocity    matlab.ui.control.NumericEditField
        Label_5             matlab.ui.control.Label
        EditFs              matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        EditScanVelocity_2  matlab.ui.control.NumericEditField
        Label_8             matlab.ui.control.Label
        Label_9             matlab.ui.control.Label
        EditScanAngle       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp;
        Fs = 80; % Description
        Radius = 1.5;
        ScanVelocity = 20;
        Angle = 50;
    end
    
    methods (Access = private)
        
        function getScanParameter(app)
            app.Fs = app.EditFs.Value;
            app.Radius = app.EditScanRadius.Value;
            app.ScanVelocity = app.EditScanVelocity.Value;  
            app.Angle = app.EditScanAngle.Value;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            movegui(app.UIFigure,'center');
            app.CallingApp = mainApp;

        end

        % Button pushed function: ButtonOK
        function ButtonOKPushed(app, event)
            getScanParameter(app);
            setScan(app.CallingApp,app.Fs,app.Radius,app.ScanVelocity,app.Angle);
            delete(app);
        end

        % Value changed function: EditScanVelocity
        function EditScanVelocityValueChanged(app, event)
            value = app.EditScanVelocity.Value;
            v = value*pi/180*app.Radius;
            app.EditScanVelocity_2.Value = v;
        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            delete(app);
        end

        % Value changed function: EditScanRadius
        function EditScanRadiusValueChanged(app, event)
            value = app.EditScanRadius.Value;
            v = value*pi/180*app.ScanVelocity.Value;
            app.EditScanVelocity_2.Value = v;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ButtonOK = uibutton(app.UIFigure, 'push');
            app.ButtonOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonOKPushed, true);
            app.ButtonOK.Text = '确定';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Text = '取消';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '扫描参数设置';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '米';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '度/秒';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '次/秒';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '米/秒';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = '扫描半径';
            app.EditScanRadius = uieditfield(app.Panel, 'numeric');
            app.EditScanRadius.Limits = [0 2];
            app.EditScanRadius.ValueChangedFcn = createCallbackFcn(app, @EditScanRadiusValueChanged, true);
            app.EditScanRadius.Value = 1.5;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '角  速  度';
            app.EditScanVelocity = uieditfield(app.Panel, 'numeric');
            app.EditScanVelocity.Limits = [0 90];
            app.EditScanVelocity.ValueChangedFcn = createCallbackFcn(app, @EditScanVelocityValueChanged, true);
            app.EditScanVelocity.Value = 20;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '采样速率';
            app.EditFs = uieditfield(app.Panel, 'numeric');
            app.EditFs.Limits = [0 200];
            app.EditFs.Value = 80;
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '线  速  度';
            app.EditScanVelocity_2 = uieditfield(app.Panel, 'numeric');
            app.EditScanVelocity_2.Limits = [0 2];
            app.EditScanVelocity_2.Enable = 'off';
            app.EditScanVelocity_2.Value = 0.4;
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '度';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '角度范围';
            app.EditScanAngle = uieditfield(app.Panel, 'numeric');
            app.EditScanAngle.Limits = [40 90];
            app.EditScanAngle.Value = 50;
        end
    end

    methods (Access = public)

        % Construct app
        function app = ScanSettings(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end